classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        Button                 matlab.ui.control.StateButton
        sample_value           matlab.ui.control.EditField
        sample_valueLabel      matlab.ui.control.Label
        Word_StockButtonGroup  matlab.ui.container.ButtonGroup
        commonsentenceButton   matlab.ui.control.RadioButton
        alphabetButton         matlab.ui.control.RadioButton
        handstatePanel         matlab.ui.container.Panel
        handstate5             matlab.ui.control.NumericEditField
        Label_5                matlab.ui.control.Label
        handstate4             matlab.ui.control.NumericEditField
        Label_4                matlab.ui.control.Label
        handstate3             matlab.ui.control.NumericEditField
        Label_3                matlab.ui.control.Label
        handstate2             matlab.ui.control.NumericEditField
        Label_2                matlab.ui.control.Label
        handstate1             matlab.ui.control.NumericEditField
        Label                  matlab.ui.control.Label
        SampleThreshold        matlab.ui.control.Spinner
        SampleThresholdLabel   matlab.ui.control.Label
        SerialParity           matlab.ui.control.DropDown
        DropDown_2Label        matlab.ui.control.Label
        SerialStopBit          matlab.ui.control.DropDown
        DropDownLabel          matlab.ui.control.Label
        SerialBaud             matlab.ui.control.DropDown
        BaudDropDownLabel      matlab.ui.control.Label
        SerialSwitch           matlab.ui.control.Switch
        TextArea               matlab.ui.control.TextArea
        serial_state           matlab.ui.control.Lamp
        LampLabel              matlab.ui.control.Label
        SerialPortNums         matlab.ui.control.DropDown
        COMDropDownLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObject % Description
        adapttemp % Description
        adapt_l % Description
        adapt_r % Description
        adaptnum % Description
        handstate % Description
        bin_handstate % Description
        sample_result % Description
        sample_threshold % Description
        gesture_frequency % Description
        word_stock % Description
        alphabet % Description
        common_sentence % Description
        state_flag % Description
        text_pointer % Description
        text % Description
    end
    
    methods (Access = private)
        
        function  Deal_serial_data(app)
        buffer=app.SerialObject.UserData;
        for n=1:32
        if ((bitand(buffer(n),hex2dec('F0')))==hex2dec('80'))&&((bitand(buffer(n+1),hex2dec('E0')))==hex2dec('00'))&&((bitand(buffer(n+2),hex2dec('E0')))==hex2dec('60'))
            switch buffer(n) 
                case hex2dec('81')
                    app.adapttemp(1) = (bitand(buffer(n+2),hex2dec('1f')))*32+buffer(n+1);
                                if (app.adapttemp(1) < app.adapt_l(1))
                              
                                    app.adapt_l(1) = app.adapttemp(1);
                                end
                                if (app.adapttemp(1) > app.adapt_r(1))
                                
                                    app.adapt_r(1) = app.adapttemp(1);
                                end
                                if (app.adapt_l(1) < app.adapt_r(1))
                                
                                    app.adaptnum(1) = (app.adapttemp(1) - app.adapt_l(1)) / (app.adapt_r(1) - app.adapt_l(1));
                                end

                                if ((app.adaptnum(1) >= 0) && (app.adaptnum(1) < 0.2))
                                
                                    app.handstate(1) = 5;
                                end
                                if ((app.adaptnum(1) >= 0.2) && (app.adaptnum(1) < 0.4))
                                    app.handstate(1)= 4;
                                end
                                if ((app.adaptnum(1) >= 0.4) && (app.adaptnum(1) < 0.6))
                                
                                    app.handstate(1) = 3;
                                end
                                if ((app.adaptnum(1) >= 0.6) && (app.adaptnum(1) < 0.8))
                                    app.handstate(1) = 2;
                                end
                                if ((app.adaptnum(1) >= 0.8) && (app.adaptnum(1) <= 1))
                                    app.handstate(1) = 1;
                                end
                                app.handstate1.Value=app.handstate(1);
                                break;
                case hex2dec('82')
                    app.adapttemp(2) = (bitand(buffer(n + 2) , hex2dec('1f')) * 32 + buffer(n+ 1));
                                if (app.adapttemp(2) < app.adapt_l(2))
                              
                                    app.adapt_l(2) = app.adapttemp(2);
                                end
                                if (app.adapttemp(2) > app.adapt_r(2))
                                
                                    app.adapt_r(2) = app.adapttemp(2);
                                end
                                if (app.adapt_l(2) < app.adapt_r(2))
                                
                                    app.adaptnum(2) = (app.adapttemp(2) - app.adapt_l(2)) / (app.adapt_r(2) - app.adapt_l(2));
                                end

                                if ((app.adaptnum(2) >= 0) && (app.adaptnum(2) < 0.2))
                                
                                    app.handstate(2) = 5;
                                end
                                if ((app.adaptnum(2) >= 0.2) && (app.adaptnum(2) < 0.4))
                                    app.handstate(2)= 4;
                                end
                                if ((app.adaptnum(2) >= 0.4) && (app.adaptnum(2) < 0.6))
                                
                                    app.handstate(2) = 3;
                                end
                                if ((app.adaptnum(2) >= 0.6) && (app.adaptnum(2) < 0.8))
                                    app.handstate(2) = 2;
                                end
                                if ((app.adaptnum(2) >= 0.8) && (app.adaptnum(2) <= 1))
                                    app.handstate(2) = 1;
                                end
                                app.handstate2.Value=app.handstate(2);
                                break;
                case hex2dec('83')
                    app.adapttemp(3) = (bitand(buffer(n+2),hex2dec('1f')))*32+buffer(n+1);
                                if (app.adapttemp(3) < app.adapt_l(3))
                              
                                    app.adapt_l(3) = app.adapttemp(3);
                                end
                                if (app.adapttemp(3) > app.adapt_r(3))
                                
                                    app.adapt_r(3) = app.adapttemp(3);
                                end
                                if (app.adapt_l(3) < app.adapt_r(3))
                                
                                    app.adaptnum(3) = (app.adapttemp(3) - app.adapt_l(3)) / (app.adapt_r(3) - app.adapt_l(3));
                                end

                                if ((app.adaptnum(3) >= 0) && (app.adaptnum(3) < 0.2))
                                
                                    app.handstate(3) = 5;
                                end
                                if ((app.adaptnum(3) >= 0.2) && (app.adaptnum(3) < 0.4))
                                    app.handstate(3)= 4;
                                end
                                if ((app.adaptnum(3) >= 0.4) && (app.adaptnum(3) < 0.6))
                                
                                    app.handstate(3) = 3;
                                end
                                if ((app.adaptnum(3) >= 0.6) && (app.adaptnum(3) < 0.8))
                                    app.handstate(3) = 2;
                                end
                                if ((app.adaptnum(3) >= 0.8) && (app.adaptnum(3) <= 1))
                                    app.handstate(3) = 1;
                                end
                                app.handstate3.Value=app.handstate(3);
                                break;
                case hex2dec('84')
                    app.adapttemp(4) = (bitand(buffer(n+2),hex2dec('1f')))*32+buffer(n+1);
                                if (app.adapttemp(4) < app.adapt_l(4))
                              
                                    app.adapt_l(4) = app.adapttemp(4);
                                end
                                if (app.adapttemp(4) > app.adapt_r(4))
                                
                                    app.adapt_r(4) = app.adapttemp(4);
                                end
                                if (app.adapt_l(4) < app.adapt_r(4))
                                
                                    app.adaptnum(4) = (app.adapttemp(4) - app.adapt_l(4)) / (app.adapt_r(4) - app.adapt_l(4));
                                end

                                if ((app.adaptnum(4) >= 0) && (app.adaptnum(4) < 0.2))
                                
                                    app.handstate(4) = 5;
                                end
                                if ((app.adaptnum(4) >= 0.2) && (app.adaptnum(4) < 0.4))
                                    app.handstate(4)= 4;
                                end
                                if ((app.adaptnum(4) >= 0.4) && (app.adaptnum(4) < 0.6))
                                
                                    app.handstate(4) = 3;
                                end
                                if ((app.adaptnum(4) >= 0.6) && (app.adaptnum(4) < 0.8))
                                    app.handstate(4) = 2;
                                end
                                if ((app.adaptnum(4) >= 0.8) && (app.adaptnum(4) <= 1))
                                    app.handstate(4) = 1;
                                end
                                app.handstate4.Value=app.handstate(4);
                                break;
                case hex2dec('85')
                    app.adapttemp(5) = (bitand(buffer(n+2),hex2dec('1f')))*32+buffer(n+1);
                                if (app.adapttemp(5) < app.adapt_l(5))
                              
                                    app.adapt_l(5) = app.adapttemp(5);
                                end
                                if (app.adapttemp(5) > app.adapt_r(5))
                                
                                    app.adapt_r(5) = app.adapttemp(5);
                                end
                                if (app.adapt_l(5) < app.adapt_r(5))
                                
                                    app.adaptnum(5) = (app.adapttemp(5) - app.adapt_l(5)) / (app.adapt_r(5) - app.adapt_l(5));
                                end

                                if ((app.adaptnum(5) >= 0) && (app.adaptnum(5) < 0.2))
                                
                                    app.handstate(5) = 5;
                                end
                                if ((app.adaptnum(5) >= 0.2) && (app.adaptnum(5) < 0.4))
                                    app.handstate(5)= 4;
                                end
                                if ((app.adaptnum(5) >= 0.4) && (app.adaptnum(5) < 0.6))
                                
                                    app.handstate(5) = 3;
                                end
                                if ((app.adaptnum(5) >= 0.6) && (app.adaptnum(5) < 0.8))
                                    app.handstate(5) = 2;
                                end
                                if ((app.adaptnum(5) >= 0.8) && (app.adaptnum(5) <= 1))
                                    app.handstate(5) = 1;
                                end
                                app.handstate5.Value=app.handstate(5);
                                break;                                
            end                              
        end
        end
        app.bin_handstate=TransHandstate(app);
        if app.sample_result~=-1
        app.sample_value.Value=num2str(app.sample_result);
        end
        scan_gesture(app);
        
        if app.commonsentenceButton.Value==1
            if(app.sample_result==0)
                app.state_flag=1;
            end
            if(app.state_flag==1&&app.sample_result~=0&&app.sample_result~=-1)
                switch app.sample_result
                    case 28
                        app.TextArea.Value="ok!";
                    case 6
                        app.TextArea.Value="yes!";
                    case 2
                        app.TextArea.Value="no!";
                    case 31
                        app.TextArea.Value="hello!";
                    case 1
                        app.TextArea.Value="good!"; 
                end
               app.state_flag=0; 
            end    
        else
        Printf(app);
        end
        end
        
        %将手指的1-5handstate值通过二值化变成0，1，并且按照大拇指到小拇指权值从小到大转换成十进制数
        function bin_handstate = TransHandstate(app)
        bin_handstate=0;
        if(app.handstate(5)<3)
            bin_handstate=bin_handstate+16;
        end
        
        if(app.handstate(4)<3)
            bin_handstate=bin_handstate+8;
        end
        
        if(app.handstate(3)<3)
            bin_handstate=bin_handstate+4;
        end
        if(app.handstate(2)<3)
            bin_handstate=bin_handstate+2;
        end
        if(app.handstate(1)<3)
            bin_handstate=bin_handstate+1;
        end
        end
        
        %扫描手势，设置采样的次数，达到一定的次数后才有效，有效解决了不同手指改变时间的先后导致的手势识别失效的错误
        function scan_gesture(app)
            app.gesture_frequency(app.bin_handstate+1)=app.gesture_frequency(app.bin_handstate+1)+1;
            if(app.gesture_frequency(app.bin_handstate+1)>app.sample_threshold)
                app.sample_result=app.bin_handstate;
                app.gesture_frequency=zeros(1,32);
            else
                app.sample_result=-1;
            end            
        end
        
        function Printf(app)
            if(app.sample_result==0)
                app.state_flag=1;
            end
            if(app.state_flag==1&&app.sample_result~=0&&app.sample_result~=-1)
                if (app.sample_result==31&&app.text_pointer>1)
                    app.text_pointer=app.text_pointer-1;
                    app.text(app.text_pointer)=' ';
                    app.TextArea.Value=app.text;
                    app.state_flag=0;
                end
                if (app.sample_result==30)
                    app.text(app.text_pointer)=' ';
                    app.text_pointer=app.text_pointer+1;
                    app.TextArea.Value=app.text;
                    app.state_flag=0;
                end
                if (app.sample_result==28)
                    app.text(app.text_pointer)="LF";
                    app.text_pointer=app.text_pointer+1;
                    app.TextArea.Value=app.text;
                    app.state_flag=0;
                end
                if (app.sample_result<length(app.word_stock))
                    app.text(app.text_pointer)=app.word_stock(app.sample_result);
                    app.text_pointer=app.text_pointer+1;
                    app.TextArea.Value=app.text;                    
                    app.state_flag=0;
                end
            end
        end
        end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end  
        app.adaptnum=zeros(1,5);
        app.adapt_l=zeros(1,5);
        app.adapt_r=zeros(1,5);
        app.adapttemp=zeros(1,5);
        app.handstate=zeros(1,5);
        for m=1:5                                    
            app.adapt_l(m)=1023;
            app.adapt_r(m)=0;
        end
        app.gesture_frequency=zeros(1,32);
        app.sample_result=-1;
        app.sample_threshold=app.SampleThreshold.Value;
        app.alphabet=['a','b','c','d','e','f','g','h','i','j','k','l','m','n','o','p','q','r','s','t','u','v','w','x','y','z'];
        app.common_sentence={'ok!','yes','no','hello!','wait a moment'};
        if app.commonsentenceButton.Value==1
            app.word_stock=app.common_sentence;
        else
            app.word_stock=app.alphabet;
        end
        app.text_pointer=1;
        app.text='';      
        end

        % Value changed function: SerialPortNums
        function SerialPortNumsOpening(app, event)
               com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end  
            
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
            if(value=='打开')
                try
                     %delete(serialport)%注销系统之前已经打开的串口资源
                     app.SerialObject=serialport( ...
                     app.SerialPortNums.Value, ...  %设置端口号
                     str2double(app.SerialBaud.Value),...%设置波特率
                    'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
                    'Parity',app.SerialParity.Value);%设置校验位
                    configureCallback(app.SerialObject,"byte",32,@readSerialData)%设置回调函数readSerialData 接受串口返回的数据 
                    app.serial_state.Color=[0,1,0];%绿色  将指示灯打开
                catch
                     errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                     app.SerialSwitch.Value='关闭';
                     return
                end
            elseif (value=='关闭')
                 delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                 app.serial_state.Color=[1,1,1];%白色  将指示灯关闭
            end
            %串口接受数据的回调函数
            function readSerialData(src,event)
              app.SerialObject.UserData=read(app.SerialObject,32,"uint8");
              Deal_serial_data(app);%处理接收到的串口数据，并显示在组件上
           end
        end

        % Value changed function: SampleThreshold
        function SampleThresholdValueChanged(app, event)
            value = app.SampleThreshold.Value;
            app.sample_threshold=app.SampleThreshold.Value;
        end

        % Selection changed function: Word_StockButtonGroup
        function Word_StockButtonGroupSelectionChanged(app, event)
            selectedButton = app.Word_StockButtonGroup.SelectedObject;
            if app.commonsentenceButton.Value==1
                app.word_stock=app.common_sentence;
            else
                app.word_stock=app.alphabet;
            end
        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value = app.Button.Value;
            app.text(app.text_pointer)=app.alphabet(app.text_pointer);
            app.text_pointer=app.text_pointer+1;
            app.TextArea.Value=app.text;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.COMDropDownLabel = uilabel(app.UIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.SerialPortNums = uidropdown(app.UIFigure);
            app.SerialPortNums.Items = {'', ''};
            app.SerialPortNums.ValueChangedFcn = createCallbackFcn(app, @SerialPortNumsOpening, true);
            app.SerialPortNums.Value = '';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.serial_state = uilamp(app.UIFigure);
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.SerialSwitch = uiswitch(app.UIFigure, 'slider');
            app.SerialSwitch.Items = {'打开', '关闭'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.BaudDropDownLabel = uilabel(app.UIFigure);
            app.BaudDropDownLabel.Text = 'Baud';
            app.SerialBaud = uidropdown(app.UIFigure);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '2400';
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = '停止位';
            app.SerialStopBit = uidropdown(app.UIFigure);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.DropDown_2Label = uilabel(app.UIFigure);
            app.DropDown_2Label.Text = '校验位';
            app.SerialParity = uidropdown(app.UIFigure);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.SampleThresholdLabel = uilabel(app.UIFigure);
            app.SampleThresholdLabel.Text = {'SampleThreshold'; ''};
            app.SampleThreshold = uispinner(app.UIFigure);
            app.SampleThreshold.Limits = [1 10];
            app.SampleThreshold.ValueChangedFcn = createCallbackFcn(app, @SampleThresholdValueChanged, true);
            app.SampleThreshold.Value = 5;
            app.handstatePanel = uipanel(app.UIFigure);
            app.handstatePanel.Title = 'handstate';
            app.Label = uilabel(app.handstatePanel);
            app.Label.Text = '1';
            app.handstate1 = uieditfield(app.handstatePanel, 'numeric');
            app.Label_2 = uilabel(app.handstatePanel);
            app.Label_2.Text = '2';
            app.handstate2 = uieditfield(app.handstatePanel, 'numeric');
            app.Label_3 = uilabel(app.handstatePanel);
            app.Label_3.Text = '3';
            app.handstate3 = uieditfield(app.handstatePanel, 'numeric');
            app.Label_4 = uilabel(app.handstatePanel);
            app.Label_4.Text = '4';
            app.handstate4 = uieditfield(app.handstatePanel, 'numeric');
            app.Label_5 = uilabel(app.handstatePanel);
            app.Label_5.Text = '5';
            app.handstate5 = uieditfield(app.handstatePanel, 'numeric');
            app.Word_StockButtonGroup = uibuttongroup(app.UIFigure);
            app.Word_StockButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @Word_StockButtonGroupSelectionChanged, true);
            app.Word_StockButtonGroup.Title = 'Word_Stock';
            app.alphabetButton = uiradiobutton(app.Word_StockButtonGroup);
            app.alphabetButton.Text = 'alphabet';
            app.alphabetButton.Value = true;
            app.commonsentenceButton = uiradiobutton(app.Word_StockButtonGroup);
            app.commonsentenceButton.Text = 'common sentence';
            app.sample_valueLabel = uilabel(app.UIFigure);
            app.sample_valueLabel.Text = 'sample_value';
            app.sample_value = uieditfield(app.UIFigure, 'text');
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Button';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end