classdef app5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        motionEditField       matlab.ui.control.EditField
        motionEditFieldLabel  matlab.ui.control.Label
        Button_2              matlab.ui.control.Button
        Button                matlab.ui.control.Button
        X7B_Panel             matlab.ui.container.Panel
        EditField_18          matlab.ui.control.EditField
        EditField_17          matlab.ui.control.EditField
        EditField_16          matlab.ui.control.EditField
        accelerations_6_z     matlab.ui.control.NumericEditField
        zEditField_10Label_2  matlab.ui.control.Label
        accelerations_6_y     matlab.ui.control.NumericEditField
        yEditField_10Label_2  matlab.ui.control.Label
        accelerations_6_x     matlab.ui.control.NumericEditField
        xEditField_10Label_2  matlab.ui.control.Label
        velocity_6_z          matlab.ui.control.NumericEditField
        zEditField_11Label_2  matlab.ui.control.Label
        velocity_6_y          matlab.ui.control.NumericEditField
        yEditField_11Label_2  matlab.ui.control.Label
        velocity_6_x          matlab.ui.control.NumericEditField
        xEditField_11Label_2  matlab.ui.control.Label
        Perspectives_6_y      matlab.ui.control.NumericEditField
        yEditField_12Label_2  matlab.ui.control.Label
        Perspectives_6_z      matlab.ui.control.NumericEditField
        zEditField_12Label_2  matlab.ui.control.Label
        Perspectives_6_x      matlab.ui.control.NumericEditField
        xEditField_12Label_2  matlab.ui.control.Label
        X77_Panel             matlab.ui.container.Panel
        EditField_15          matlab.ui.control.EditField
        EditField_14          matlab.ui.control.EditField
        EditField_13          matlab.ui.control.EditField
        accelerations_5_z     matlab.ui.control.NumericEditField
        zEditField_4Label_2   matlab.ui.control.Label
        accelerations_5_y     matlab.ui.control.NumericEditField
        yEditField_4Label_2   matlab.ui.control.Label
        accelerations_5_x     matlab.ui.control.NumericEditField
        xEditField_4Label_2   matlab.ui.control.Label
        velocity_5_z          matlab.ui.control.NumericEditField
        zEditField_5Label_2   matlab.ui.control.Label
        velocity_5_y          matlab.ui.control.NumericEditField
        yEditField_5Label_2   matlab.ui.control.Label
        velocity_5_x          matlab.ui.control.NumericEditField
        xEditField_5Label_2   matlab.ui.control.Label
        Perspectives_5_y      matlab.ui.control.NumericEditField
        yEditField_6Label_2   matlab.ui.control.Label
        Perspectives_5_z      matlab.ui.control.NumericEditField
        zEditField_6Label_2   matlab.ui.control.Label
        Perspectives_5_x      matlab.ui.control.NumericEditField
        xEditField_6Label_2   matlab.ui.control.Label
        textEditField         matlab.ui.control.EditField
        textEditFieldLabel    matlab.ui.control.Label
        Picture_Lamp          matlab.ui.control.Lamp
        Picture_LampLabel     matlab.ui.control.Label
        X7A_Panel             matlab.ui.container.Panel
        EditField_12          matlab.ui.control.EditField
        EditField_11          matlab.ui.control.EditField
        EditField_10          matlab.ui.control.EditField
        accelerations_4_z     matlab.ui.control.NumericEditField
        zEditField_10Label    matlab.ui.control.Label
        accelerations_4_y     matlab.ui.control.NumericEditField
        yEditField_10Label    matlab.ui.control.Label
        accelerations_4_x     matlab.ui.control.NumericEditField
        xEditField_10Label    matlab.ui.control.Label
        velocity_4_z          matlab.ui.control.NumericEditField
        zEditField_11Label    matlab.ui.control.Label
        velocity_4_y          matlab.ui.control.NumericEditField
        yEditField_11Label    matlab.ui.control.Label
        velocity_4_x          matlab.ui.control.NumericEditField
        xEditField_11Label    matlab.ui.control.Label
        Perspectives_4_y      matlab.ui.control.NumericEditField
        yEditField_12Label    matlab.ui.control.Label
        Perspectives_4_z      matlab.ui.control.NumericEditField
        zEditField_12Label    matlab.ui.control.Label
        Perspectives_4_x      matlab.ui.control.NumericEditField
        xEditField_12Label    matlab.ui.control.Label
        X79_Panel             matlab.ui.container.Panel
        EditField_9           matlab.ui.control.EditField
        EditField_8           matlab.ui.control.EditField
        EditField_7           matlab.ui.control.EditField
        accelerations_3_z     matlab.ui.control.NumericEditField
        zEditField_7Label     matlab.ui.control.Label
        accelerations_3_y     matlab.ui.control.NumericEditField
        yEditField_7Label     matlab.ui.control.Label
        accelerations_3_x     matlab.ui.control.NumericEditField
        xEditField_7Label     matlab.ui.control.Label
        velocity_3_z          matlab.ui.control.NumericEditField
        zEditField_8Label     matlab.ui.control.Label
        velocity_3_y          matlab.ui.control.NumericEditField
        yEditField_8Label     matlab.ui.control.Label
        velocity_3_x          matlab.ui.control.NumericEditField
        xEditField_8Label     matlab.ui.control.Label
        Perspectives_3_y      matlab.ui.control.NumericEditField
        yEditField_9Label     matlab.ui.control.Label
        Perspectives_3_z      matlab.ui.control.NumericEditField
        zEditField_9Label     matlab.ui.control.Label
        Perspectives_3_x      matlab.ui.control.NumericEditField
        xEditField_9Label     matlab.ui.control.Label
        X78_Panel             matlab.ui.container.Panel
        EditField_6           matlab.ui.control.EditField
        EditField_5           matlab.ui.control.EditField
        EditField_4           matlab.ui.control.EditField
        accelerations_2_z     matlab.ui.control.NumericEditField
        zEditField_4Label     matlab.ui.control.Label
        accelerations_2_y     matlab.ui.control.NumericEditField
        yEditField_4Label     matlab.ui.control.Label
        accelerations_2_x     matlab.ui.control.NumericEditField
        xEditField_4Label     matlab.ui.control.Label
        velocity_2_z          matlab.ui.control.NumericEditField
        zEditField_5Label     matlab.ui.control.Label
        velocity_2_y          matlab.ui.control.NumericEditField
        yEditField_5Label     matlab.ui.control.Label
        velocity_2_x          matlab.ui.control.NumericEditField
        xEditField_5Label     matlab.ui.control.Label
        Perspectives_2_y      matlab.ui.control.NumericEditField
        yEditField_6Label     matlab.ui.control.Label
        Perspectives_2_z      matlab.ui.control.NumericEditField
        zEditField_6Label     matlab.ui.control.Label
        Perspectives_2_x      matlab.ui.control.NumericEditField
        xEditField_6Label     matlab.ui.control.Label
        X76_Panel             matlab.ui.container.Panel
        EditField_3           matlab.ui.control.EditField
        EditField_2           matlab.ui.control.EditField
        EditField             matlab.ui.control.EditField
        accelerations_1_z     matlab.ui.control.NumericEditField
        zEditFieldLabel       matlab.ui.control.Label
        accelerations_1_y     matlab.ui.control.NumericEditField
        yEditFieldLabel       matlab.ui.control.Label
        accelerations_1_x     matlab.ui.control.NumericEditField
        xEditFieldLabel       matlab.ui.control.Label
        velocity_1_z          matlab.ui.control.NumericEditField
        zEditField_2Label     matlab.ui.control.Label
        velocity_1_y          matlab.ui.control.NumericEditField
        yEditField_2Label     matlab.ui.control.Label
        velocity_1_x          matlab.ui.control.NumericEditField
        xEditField_2Label     matlab.ui.control.Label
        Perspectives_1_y      matlab.ui.control.NumericEditField
        yEditField_3Label     matlab.ui.control.Label
        Perspectives_1_z      matlab.ui.control.NumericEditField
        zEditField_3Label     matlab.ui.control.Label
        Perspectives_1_x      matlab.ui.control.NumericEditField
        xEditField_3Label     matlab.ui.control.Label
        stateEditField        matlab.ui.control.EditField
        stateEditFieldLabel   matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        serial_state          matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        SerialSwitch          matlab.ui.control.Switch
        SerialStopBit         matlab.ui.control.DropDown
        DropDownLabel         matlab.ui.control.Label
        SerialParity          matlab.ui.control.DropDown
        DropDown_2Label       matlab.ui.control.Label
        SerialBaud            matlab.ui.control.DropDown
        BaudDropDownLabel     matlab.ui.control.Label
        SerialPortNums        matlab.ui.control.DropDown
        COMDropDownLabel      matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)  % 传感器参数定义
        SerialObject               % Description
        
        accelerations_1            % 1_加速度
        accelerations_2            % 2_加速度
        accelerations_3            % 3_加速度
        accelerations_4            % 4_加速度
        accelerations_5            % 5_加速度
        accelerations_6            % 6_加速度

        velocity_1                 % 1_速度
        velocity_2                 % 2_速度
        velocity_3                 % 3_速度
        velocity_4                 % 4_速度
        velocity_5                 % 5_速度
        velocity_6                 % 6_速度

        perspectives_1             % 1_角度
        perspectives_2             % 2_角度
        perspectives_3             % 3_角度
        perspectives_4             % 4_角度
        perspectives_5             % 5_角度
        perspectives_6             % 6_角度

        alldata

        fp

        perspectives_5_init % Description

        perspectives_2_init % Description
        perspectives_3_init % Description
        perspectives_1_init % Description
        perspectives_4_init % Description
    end
    

    properties (Access = private)   % 图像显示文件定义
        % 绝对路径读取图像数据

        %单脚连续动作
        file_heel_rotation='D:\桌面\app2\app+图\连续脚跟旋转_file_1.png';
        file_ankle_flexion='D:\桌面\app2\app+图\连续脚踝弯曲_file_2.png';
        file_leg_flexion='D:\桌面\app2\app+图\连续抬腿_file_3.png';
        
        %单脚保持动作
        file_keep_plantar_flexion='D:\桌面\app2\app+图\保持放松_file_4.png';
        file_keep_toes_flexion='D:\桌面\app2\app+图\保持脚趾弯曲_file_5.png';
        file_keep_big_toe_up='D:\桌面\app2\app+图\保持抬大拇指_file_6.png';

        %单脚单次动作
        file_tap='D:\桌面\app2\app+图\单击_file_7.png';
        file_double_tap='D:\桌面\app2\app+图\双击_file_8.png';
        file_kick_up='D:\桌面\app2\app+图\单踢腿_file_9.png';
        file_rotate_left='D:\桌面\app2\app+图\向内旋转_file_10.png';
        file_rotate_right='D:\桌面\app2\app+图\向外旋转_file_11.png';
        file_big_toe_up='D:\桌面\app2\app+图\大拇指单击_file_12.png';
        file_toes_plantar_flexion='D:\桌面\app2\app+图\脚趾单次弯曲_file_13.png';

        %双脚组合动作
        file_heel_to_hell='D:\桌面\app2\app+图\脚跟靠拢_file_14.png';
        file_toe_to_toe='D:\桌面\app2\app+图\脚尖靠拢_file_15.png';
        file_close_up='D:\桌面\app2\app+图\双脚并拢_file_16.png';
        file_feet_overlap='D:\桌面\app2\app+图\双脚交叉_file_17.png';

        %暂无动作
        file_no_actions='D:\桌面\app2\app+图\等待_file_18.png';

    end
    

methods (Access = private)


function dealdata(app)                              %读取数据并显示
    for i=1:length(app.SerialObject.UserData)-2         %解码数据，arduino数据是以数组形式传出，读取为字符数组形式。
%                  motion_imshow(app);  
        first_double_character=[app.SerialObject.UserData(i),app.SerialObject.UserData(i+1)];
        app.textEditField.Value=first_double_character;
switch first_double_character
    case ['1','a']
        read_data_0X76_1a(app,i);
    case ['1','v']
        read_data_0X76_1v(app,i);

    case ['1','p']
        read_data_0X76_1p(app,i);
            
    case ['2','a']
        read_data_0X78_2a(app,i);

    case ['2','v']
        read_data_0X78_2v(app,i);

    case ['2','p']
        read_data_0X78_2p(app,i);
        
    case ['3','a']
        read_data_0X79_3a(app,i);
            
    case ['3','v']
        read_data_0X79_3v(app,i);

    case ['3','p']
        read_data_0X79_3p(app,i);

    case ['4','a']
        read_data_0X7A_4a(app,i);
            
    case ['4','v']
        read_data_0X7A_4v(app,i);

    case ['4','p']
        read_data_0X7A_4p(app,i);

    case ['5','a']
        read_data_0X77_5a(app,i);
            
    case ['5','v']
        read_data_0X77_5v(app,i);

    case ['5','p']
        read_data_0X77_5p(app,i);

    case ['6','a']
        read_data_0X7B_6a(app,i);
            
    case ['6','v']
        read_data_0X7B_6v(app,i);

    case ['6','p']
        read_data_0X7B_6p(app,i);

end

   
    end
    %数据赋值给显示框
app.alldata=[1,app.accelerations_1,app.velocity_1,app.perspectives_1,...
             2,app.accelerations_2,app.velocity_2,app.perspectives_2,...
             3,app.accelerations_3,app.velocity_3,app.perspectives_3,...
             4,app.accelerations_4,app.velocity_4,app.perspectives_4,...
             5,app.accelerations_5,app.velocity_5,app.perspectives_5,...
             6,app.accelerations_6,app.velocity_6,app.perspectives_6];
end


%     function imshow_realtime_actions(app)               %分析实时动作
% 
%             if (app.velocity_3_y.Value>=100)
%                 im=imread(app.file_heel_rotation);
%                 axis(app.UIAxes,"off");
%                 imshow(im,'Parent',app.UIAxes);
%                 app.Picture_Lamp.Color=[1,0,0];
%             else
%                 im=imread(app.file_no_actions);
%                 axis(app.UIAxes,"off");
%                 imshow(im,'Parent',app.UIAxes);
%                 app.Picture_Lamp.Color=[0,1,0];
%             end
%     end


    function read_data_0X76_1a(app,i)
            L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_1(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_1_x.Value=app.accelerations_1(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_1(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_1_y.Value=app.accelerations_1(2);
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_1(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.accelerations_1_z.Value=app.accelerations_1(3);
    end


    function read_data_0X76_1v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_1(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_1_x.Value=app.velocity_1(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_1(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_1_y.Value=app.velocity_1(2);
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_1(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.velocity_1_z.Value=app.velocity_1(3);
    end

   
    function read_data_0X76_1p(app,i)
            L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_1(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_1_x.Value=app.perspectives_1(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_1(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_1_y.Value=app.perspectives_1(2);
            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_1(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1)); 
            app.Perspectives_1_z.Value=app.perspectives_1(3);
    end

        
    function read_data_0X78_2a(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_2(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_2_x.Value=app.accelerations_2(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_2(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_2_y.Value=app.accelerations_2(2);
            
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_2(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1)); 
            app.accelerations_2_z.Value=app.accelerations_2(3);
    end

        
    function read_data_0X78_2v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_2(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_2_x.Value=app.velocity_2(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_2(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_2_y.Value=app.velocity_2(2);
            
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_2(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));  
            app.velocity_2_z.Value=app.velocity_2(3);
    end

     
    function read_data_0X78_2p(app,i)
        L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_2(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_2_x.Value=app.perspectives_2(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_2(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_2_y.Value=app.perspectives_2(2);
            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_2(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.Perspectives_2_z.Value=app.perspectives_2(3);
    end

        
    function read_data_0X79_3a(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_3(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_3_x.Value=app.accelerations_3(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_3(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_3_y.Value=app.accelerations_3(2);

        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_3(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.accelerations_3_z.Value=app.accelerations_3(3);
    end


    function read_data_0X79_3v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_3(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_3_x.Value=app.velocity_3(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_3(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_3_y.Value=app.velocity_3(2);
            
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_3(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.velocity_3_z.Value=app.velocity_3(3);
    end


    function read_data_0X79_3p(app,i)
            L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_3(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_3_x.Value=app.perspectives_3(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_3(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_3_y.Value=app.perspectives_3(2);

            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_3(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.Perspectives_3_z.Value=app.perspectives_3(3);
    end

    
    function read_data_0X7A_4a(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_4(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_4_x.Value=app.accelerations_4(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_4(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_4_y.Value=app.accelerations_4(2);

        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_4(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.accelerations_4_z.Value=app.accelerations_4(3);
    end

    
    function read_data_0X7A_4v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_4(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_4_x.Value=app.velocity_4(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_4(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_4_y.Value=app.velocity_4(2);
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_4(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));  
            app.velocity_4_z.Value=app.velocity_4(3);
    end


    function read_data_0X7A_4p(app,i)
        L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_4(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_4_x.Value=app.perspectives_4(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_4(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_4_y.Value=app.perspectives_4(2);
            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_4(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1)); 
            app.Perspectives_4_z.Value=app.perspectives_4(3);
    end

    function read_data_0X77_5a(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_5(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_5_x.Value=app.accelerations_5(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_5(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_5_y.Value=app.accelerations_5(2);

        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_5(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.accelerations_5_z.Value=app.accelerations_5(3);
    end

    
    function read_data_0X77_5v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_5(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_5_x.Value=app.velocity_5(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_5(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_5_y.Value=app.velocity_5(2);
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_5(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));  
            app.velocity_5_z.Value=app.velocity_5(3);
    end


    function read_data_0X77_5p(app,i)
        L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_5(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_5_x.Value=app.perspectives_5(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_5(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_5_y.Value=app.perspectives_5(2);
            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_5(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1)); 
            app.Perspectives_5_z.Value=app.perspectives_5(3);
    end

    function read_data_0X7B_6a(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_6(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.accelerations_6_x.Value=app.accelerations_6(1);
            
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_6(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.accelerations_6_y.Value=app.accelerations_6(2);

        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.accelerations_6(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));
            app.accelerations_6_z.Value=app.accelerations_6(3);
    end

    
    function read_data_0X7B_6v(app,i)
        L1=0;
        while 1
            if ((i+2+L1)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+2)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_6(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.velocity_6_x.Value=app.velocity_6(1);
        L2=0;
       while 1
            if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+3)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_6(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.velocity_6_y.Value=app.velocity_6(2);
        L3=0;
        while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            app.velocity_6(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1));  
            app.velocity_6_z.Value=app.velocity_6(3);
    end


    function read_data_0X7B_6p(app,i)
        L1=0;
            while 1
                if ((i+2+L1)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+2)~="$"
                        L1=L1+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_6(1)=str2double(app.SerialObject.UserData(i+2:i+2+L1-1));
            app.Perspectives_6_x.Value=app.perspectives_6(1);
            
            L2=0;
            while 1
                if ((i+3+L1+L2)<length(app.SerialObject.UserData))
                    if app.SerialObject.UserData(i+L1+L2+3)~="$"
                        L2=L2+1;
                    else
                        break;
                    end
                else
                    break;
                end
            end
            app.perspectives_6(2)=str2double(app.SerialObject.UserData(i+3+L1:i+3+L1+L2-1));
            app.Perspectives_6_y.Value=app.perspectives_6(2);
            
            L3=0;
            while 1
            if ((i+4+L1+L2+L3)<length(app.SerialObject.UserData))
                if app.SerialObject.UserData(i+L1+L2+L3+4)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
            end
            app.perspectives_6(3)=str2double(app.SerialObject.UserData(i+4+L1+L2:i+4+L1+L2+L3-1)); 
            app.Perspectives_6_z.Value=app.perspectives_6(3);
    end


    function results = motion_imshow(app)
            if app.perspectives_4(2)-app.perspectives_4_init(2)>10
                app.motionEditField.Value='左旋转';
                im=imread(app.file_rotate_left);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            else if app.perspectives_4(2)-app.perspectives_4_init(2)<-30
                app.motionEditField.Value='右旋转';
                im=imread(app.file_rotate_right);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            else if app.perspectives_1(1)-app.perspectives_1_init(1)>30
                app.motionEditField.Value='单击';
                im=imread(app.file_tap);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            else if app.perspectives_3(1)-app.perspectives_3_init(1)<0&&app.perspectives_5(1)-app.perspectives_5_init(1)>60&&app.perspectives_2(1)-app.perspectives_2_init(1)>60
                app.motionEditField.Value='大拇指抬起';                 
                im=imread(app.file_big_toe_up);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            else if app.perspectives_3(1)-app.perspectives_3_init(1)>60&&app.perspectives_5(1)-app.perspectives_5_init(1)>60&&app.perspectives_2(1)-app.perspectives_2_init(1)>60
                app.motionEditField.Value='所有脚趾保持弯曲';
                im=imread(app.file_toes_plantar_flexion);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            else   
                app.motionEditField.Value='保持放松';
                im=imread(app.file_keep_plantar_flexion);
                axis(app.UIAxes,"off");
                imshow(im,'Parent',app.UIAxes);
            end
            end
            end
            end
            end
            end
end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             com_list=serialportlist("all");%找到可以用的串口号

             format long g

             app.accelerations_1=zeros(1,3);
             app.accelerations_2=zeros(1,3);
             app.accelerations_3=zeros(1,3);
             app.accelerations_4=zeros(1,3);
             app.accelerations_5=zeros(1,3);
             app.accelerations_6=zeros(1,3);

             app.velocity_1=zeros(1,3);
             app.velocity_2=zeros(1,3);
             app.velocity_3=zeros(1,3);
             app.velocity_4=zeros(1,3);
             app.velocity_5=zeros(1,3);
             app.velocity_6=zeros(1,3);

             app.perspectives_1=zeros(1,3);
             app.perspectives_2=zeros(1,3);
             app.perspectives_3=zeros(1,3);
             app.perspectives_4=zeros(1,3);
             app.perspectives_5=zeros(1,3);
             app.perspectives_6=zeros(1,3);
             
             app.perspectives_1_init=zeros(1,3);
             app.perspectives_2_init=zeros(1,3);
             app.perspectives_3_init=zeros(1,3);
             app.perspectives_5_init=zeros(1,3);
             app.perspectives_4_init=zeros(1,3);

             app.alldata=zeros(6,10);

             app.fp=fopen('A.txt','w');
             im=imread(app.file_no_actions);
             axis(app.UIAxes,"off");
             imshow(im,'Parent',app.UIAxes);

           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
           end
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
                com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end  
            
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
             if(value=='打开')
%                     serialComName = 'COM14';
%                     serialBaudrate = 9600;
%                     serialDataBit = 8;
%                     serialCheckBit = 'none';
%                     serialStopBit = 1;
                try
%                    delete(serialport)%注销系统之前已经打开的串口资源
                     app.SerialObject=serialport( ...
                     app.SerialPortNums.Value, ...  %设置端口号
                     str2double(app.SerialBaud.Value),...%设置波特率
                    'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
                    'Parity',app.SerialParity.Value);%设置校验位

%                     app.SerialObject=serialport(serialComName,serialBaudrate,"Parity",serialCheckBit,"DataBits",serialDataBit,"StopBits",serialStopBit,"Timeout",1);
                    
                    configureCallback(app.SerialObject,"byte",512,@readSerialData)%设置回调函数readSerialData 接受串口返回的数据 
                    app.serial_state.Color=[0,1,0];%绿色  将指示灯打开

                catch
                     errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                     app.SerialSwitch.Value='关闭';
                     return
                end
            elseif (value=='关闭')
                 delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                 app.serial_state.Color=[1,1,1];%白色  将指示灯关闭
                 app.Picture_Lamp.Color=[1,1,1];
            end
            %串口接受数据的回调函数
            function readSerialData(src,event)
              app.SerialObject.UserData=read(app.SerialObject,512,"char");
              dealdata(app);%处理接收到的串口数据，并显示在组件上
              motion_imshow(app);  
            

           end
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)

            fprintf(app.fp,['%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,' ...
                            '%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,' ...
                            '%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,' ...
                            '%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,' ...
                            '%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,' ...
                            '%0.0f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.2f,%0.0f,%0.0f,%0.0f,\r\n'],app.alldata);

        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.perspectives_1_init=app.perspectives_1;
            app.perspectives_2_init=app.perspectives_2;
            app.perspectives_3_init=app.perspectives_3;
            app.perspectives_5_init=app.perspectives_5;
            app.perspectives_4_init=app.perspectives_4;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '正在进行的动作')
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '串口选择';
            app.COMDropDownLabel = uilabel(app.Panel);
            app.COMDropDownLabel.Text = 'COM';
            app.SerialPortNums = uidropdown(app.Panel);
            app.SerialPortNums.Items = {'', ''};
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.SerialPortNums.Value = '';
            app.BaudDropDownLabel = uilabel(app.Panel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.SerialBaud = uidropdown(app.Panel);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '2400';
            app.DropDown_2Label = uilabel(app.Panel);
            app.DropDown_2Label.Text = '校验位';
            app.SerialParity = uidropdown(app.Panel);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = '停止位';
            app.SerialStopBit = uidropdown(app.Panel);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.SerialSwitch = uiswitch(app.Panel, 'slider');
            app.SerialSwitch.Items = {'打开', '关闭'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.LampLabel = uilabel(app.Panel);
            app.LampLabel.Text = 'Lamp';
            app.serial_state = uilamp(app.Panel);
            app.stateEditFieldLabel = uilabel(app.UIFigure);
            app.stateEditFieldLabel.Text = 'state';
            app.stateEditField = uieditfield(app.UIFigure, 'text');
            app.X76_Panel = uipanel(app.UIFigure);
            app.X76_Panel.Title = '0X76_脚背';
            app.xEditField_3Label = uilabel(app.X76_Panel);
            app.xEditField_3Label.Text = 'x';
            app.Perspectives_1_x = uieditfield(app.X76_Panel, 'numeric');
            app.zEditField_3Label = uilabel(app.X76_Panel);
            app.zEditField_3Label.Text = 'z';
            app.Perspectives_1_z = uieditfield(app.X76_Panel, 'numeric');
            app.yEditField_3Label = uilabel(app.X76_Panel);
            app.yEditField_3Label.Text = 'y';
            app.Perspectives_1_y = uieditfield(app.X76_Panel, 'numeric');
            app.xEditField_2Label = uilabel(app.X76_Panel);
            app.xEditField_2Label.Text = 'x';
            app.velocity_1_x = uieditfield(app.X76_Panel, 'numeric');
            app.yEditField_2Label = uilabel(app.X76_Panel);
            app.yEditField_2Label.Text = 'y';
            app.velocity_1_y = uieditfield(app.X76_Panel, 'numeric');
            app.zEditField_2Label = uilabel(app.X76_Panel);
            app.zEditField_2Label.Text = 'z';
            app.velocity_1_z = uieditfield(app.X76_Panel, 'numeric');
            app.xEditFieldLabel = uilabel(app.X76_Panel);
            app.xEditFieldLabel.Text = 'x';
            app.accelerations_1_x = uieditfield(app.X76_Panel, 'numeric');
            app.yEditFieldLabel = uilabel(app.X76_Panel);
            app.yEditFieldLabel.Text = 'y';
            app.accelerations_1_y = uieditfield(app.X76_Panel, 'numeric');
            app.zEditFieldLabel = uilabel(app.X76_Panel);
            app.zEditFieldLabel.Text = 'z';
            app.accelerations_1_z = uieditfield(app.X76_Panel, 'numeric');
            app.EditField = uieditfield(app.X76_Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Accelerations';
            app.EditField_2 = uieditfield(app.X76_Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Angle velocity';
            app.EditField_3 = uieditfield(app.X76_Panel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Perspectives';
            app.X78_Panel = uipanel(app.UIFigure);
            app.X78_Panel.Title = '0X78_食指';
            app.xEditField_6Label = uilabel(app.X78_Panel);
            app.xEditField_6Label.Text = 'x';
            app.Perspectives_2_x = uieditfield(app.X78_Panel, 'numeric');
            app.zEditField_6Label = uilabel(app.X78_Panel);
            app.zEditField_6Label.Text = 'z';
            app.Perspectives_2_z = uieditfield(app.X78_Panel, 'numeric');
            app.yEditField_6Label = uilabel(app.X78_Panel);
            app.yEditField_6Label.Text = 'y';
            app.Perspectives_2_y = uieditfield(app.X78_Panel, 'numeric');
            app.xEditField_5Label = uilabel(app.X78_Panel);
            app.xEditField_5Label.Text = 'x';
            app.velocity_2_x = uieditfield(app.X78_Panel, 'numeric');
            app.yEditField_5Label = uilabel(app.X78_Panel);
            app.yEditField_5Label.Text = 'y';
            app.velocity_2_y = uieditfield(app.X78_Panel, 'numeric');
            app.zEditField_5Label = uilabel(app.X78_Panel);
            app.zEditField_5Label.Text = 'z';
            app.velocity_2_z = uieditfield(app.X78_Panel, 'numeric');
            app.xEditField_4Label = uilabel(app.X78_Panel);
            app.xEditField_4Label.Text = 'x';
            app.accelerations_2_x = uieditfield(app.X78_Panel, 'numeric');
            app.yEditField_4Label = uilabel(app.X78_Panel);
            app.yEditField_4Label.Text = 'y';
            app.accelerations_2_y = uieditfield(app.X78_Panel, 'numeric');
            app.zEditField_4Label = uilabel(app.X78_Panel);
            app.zEditField_4Label.Text = 'z';
            app.accelerations_2_z = uieditfield(app.X78_Panel, 'numeric');
            app.EditField_4 = uieditfield(app.X78_Panel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Accelerations';
            app.EditField_5 = uieditfield(app.X78_Panel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Angle velocity';
            app.EditField_6 = uieditfield(app.X78_Panel, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Perspectives';
            app.X79_Panel = uipanel(app.UIFigure);
            app.X79_Panel.Title = '0X79_大拇指';
            app.xEditField_9Label = uilabel(app.X79_Panel);
            app.xEditField_9Label.Text = 'x';
            app.Perspectives_3_x = uieditfield(app.X79_Panel, 'numeric');
            app.zEditField_9Label = uilabel(app.X79_Panel);
            app.zEditField_9Label.Text = 'z';
            app.Perspectives_3_z = uieditfield(app.X79_Panel, 'numeric');
            app.yEditField_9Label = uilabel(app.X79_Panel);
            app.yEditField_9Label.Text = 'y';
            app.Perspectives_3_y = uieditfield(app.X79_Panel, 'numeric');
            app.xEditField_8Label = uilabel(app.X79_Panel);
            app.xEditField_8Label.Text = 'x';
            app.velocity_3_x = uieditfield(app.X79_Panel, 'numeric');
            app.yEditField_8Label = uilabel(app.X79_Panel);
            app.yEditField_8Label.Text = 'y';
            app.velocity_3_y = uieditfield(app.X79_Panel, 'numeric');
            app.zEditField_8Label = uilabel(app.X79_Panel);
            app.zEditField_8Label.Text = 'z';
            app.velocity_3_z = uieditfield(app.X79_Panel, 'numeric');
            app.xEditField_7Label = uilabel(app.X79_Panel);
            app.xEditField_7Label.Text = 'x';
            app.accelerations_3_x = uieditfield(app.X79_Panel, 'numeric');
            app.yEditField_7Label = uilabel(app.X79_Panel);
            app.yEditField_7Label.Text = 'y';
            app.accelerations_3_y = uieditfield(app.X79_Panel, 'numeric');
            app.zEditField_7Label = uilabel(app.X79_Panel);
            app.zEditField_7Label.Text = 'z';
            app.accelerations_3_z = uieditfield(app.X79_Panel, 'numeric');
            app.EditField_7 = uieditfield(app.X79_Panel, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Accelerations';
            app.EditField_8 = uieditfield(app.X79_Panel, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Angle velocity';
            app.EditField_9 = uieditfield(app.X79_Panel, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 'Perspectives';
            app.X7A_Panel = uipanel(app.UIFigure);
            app.X7A_Panel.Title = '0X7A_小腿';
            app.xEditField_12Label = uilabel(app.X7A_Panel);
            app.xEditField_12Label.Text = 'x';
            app.Perspectives_4_x = uieditfield(app.X7A_Panel, 'numeric');
            app.zEditField_12Label = uilabel(app.X7A_Panel);
            app.zEditField_12Label.Text = 'z';
            app.Perspectives_4_z = uieditfield(app.X7A_Panel, 'numeric');
            app.yEditField_12Label = uilabel(app.X7A_Panel);
            app.yEditField_12Label.Text = 'y';
            app.Perspectives_4_y = uieditfield(app.X7A_Panel, 'numeric');
            app.xEditField_11Label = uilabel(app.X7A_Panel);
            app.xEditField_11Label.Text = 'x';
            app.velocity_4_x = uieditfield(app.X7A_Panel, 'numeric');
            app.yEditField_11Label = uilabel(app.X7A_Panel);
            app.yEditField_11Label.Text = 'y';
            app.velocity_4_y = uieditfield(app.X7A_Panel, 'numeric');
            app.zEditField_11Label = uilabel(app.X7A_Panel);
            app.zEditField_11Label.Text = 'z';
            app.velocity_4_z = uieditfield(app.X7A_Panel, 'numeric');
            app.xEditField_10Label = uilabel(app.X7A_Panel);
            app.xEditField_10Label.Text = 'x';
            app.accelerations_4_x = uieditfield(app.X7A_Panel, 'numeric');
            app.yEditField_10Label = uilabel(app.X7A_Panel);
            app.yEditField_10Label.Text = 'y';
            app.accelerations_4_y = uieditfield(app.X7A_Panel, 'numeric');
            app.zEditField_10Label = uilabel(app.X7A_Panel);
            app.zEditField_10Label.Text = 'z';
            app.accelerations_4_z = uieditfield(app.X7A_Panel, 'numeric');
            app.EditField_10 = uieditfield(app.X7A_Panel, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Value = 'Accelerations';
            app.EditField_11 = uieditfield(app.X7A_Panel, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_11.Value = 'Angle velocity';
            app.EditField_12 = uieditfield(app.X7A_Panel, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Value = 'Perspectives';
            app.Picture_LampLabel = uilabel(app.UIFigure);
            app.Picture_LampLabel.Text = 'Picture_Lamp';
            app.Picture_Lamp = uilamp(app.UIFigure);
            app.textEditFieldLabel = uilabel(app.UIFigure);
            app.textEditFieldLabel.Text = 'text';
            app.textEditField = uieditfield(app.UIFigure, 'text');
            app.X77_Panel = uipanel(app.UIFigure);
            app.X77_Panel.Title = '0X77_无名指';
            app.xEditField_6Label_2 = uilabel(app.X77_Panel);
            app.xEditField_6Label_2.Text = 'x';
            app.Perspectives_5_x = uieditfield(app.X77_Panel, 'numeric');
            app.zEditField_6Label_2 = uilabel(app.X77_Panel);
            app.zEditField_6Label_2.Text = 'z';
            app.Perspectives_5_z = uieditfield(app.X77_Panel, 'numeric');
            app.yEditField_6Label_2 = uilabel(app.X77_Panel);
            app.yEditField_6Label_2.Text = 'y';
            app.Perspectives_5_y = uieditfield(app.X77_Panel, 'numeric');
            app.xEditField_5Label_2 = uilabel(app.X77_Panel);
            app.xEditField_5Label_2.Text = 'x';
            app.velocity_5_x = uieditfield(app.X77_Panel, 'numeric');
            app.yEditField_5Label_2 = uilabel(app.X77_Panel);
            app.yEditField_5Label_2.Text = 'y';
            app.velocity_5_y = uieditfield(app.X77_Panel, 'numeric');
            app.zEditField_5Label_2 = uilabel(app.X77_Panel);
            app.zEditField_5Label_2.Text = 'z';
            app.velocity_5_z = uieditfield(app.X77_Panel, 'numeric');
            app.xEditField_4Label_2 = uilabel(app.X77_Panel);
            app.xEditField_4Label_2.Text = 'x';
            app.accelerations_5_x = uieditfield(app.X77_Panel, 'numeric');
            app.yEditField_4Label_2 = uilabel(app.X77_Panel);
            app.yEditField_4Label_2.Text = 'y';
            app.accelerations_5_y = uieditfield(app.X77_Panel, 'numeric');
            app.zEditField_4Label_2 = uilabel(app.X77_Panel);
            app.zEditField_4Label_2.Text = 'z';
            app.accelerations_5_z = uieditfield(app.X77_Panel, 'numeric');
            app.EditField_13 = uieditfield(app.X77_Panel, 'text');
            app.EditField_13.Editable = 'off';
            app.EditField_13.Value = 'Accelerations';
            app.EditField_14 = uieditfield(app.X77_Panel, 'text');
            app.EditField_14.Editable = 'off';
            app.EditField_14.Value = 'Angle velocity';
            app.EditField_15 = uieditfield(app.X77_Panel, 'text');
            app.EditField_15.Editable = 'off';
            app.EditField_15.Value = 'Perspectives';
            app.X7B_Panel = uipanel(app.UIFigure);
            app.X7B_Panel.Title = '0X7B_脚后跟';
            app.xEditField_12Label_2 = uilabel(app.X7B_Panel);
            app.xEditField_12Label_2.Text = 'x';
            app.Perspectives_6_x = uieditfield(app.X7B_Panel, 'numeric');
            app.zEditField_12Label_2 = uilabel(app.X7B_Panel);
            app.zEditField_12Label_2.Text = 'z';
            app.Perspectives_6_z = uieditfield(app.X7B_Panel, 'numeric');
            app.yEditField_12Label_2 = uilabel(app.X7B_Panel);
            app.yEditField_12Label_2.Text = 'y';
            app.Perspectives_6_y = uieditfield(app.X7B_Panel, 'numeric');
            app.xEditField_11Label_2 = uilabel(app.X7B_Panel);
            app.xEditField_11Label_2.Text = 'x';
            app.velocity_6_x = uieditfield(app.X7B_Panel, 'numeric');
            app.yEditField_11Label_2 = uilabel(app.X7B_Panel);
            app.yEditField_11Label_2.Text = 'y';
            app.velocity_6_y = uieditfield(app.X7B_Panel, 'numeric');
            app.zEditField_11Label_2 = uilabel(app.X7B_Panel);
            app.zEditField_11Label_2.Text = 'z';
            app.velocity_6_z = uieditfield(app.X7B_Panel, 'numeric');
            app.xEditField_10Label_2 = uilabel(app.X7B_Panel);
            app.xEditField_10Label_2.Text = 'x';
            app.accelerations_6_x = uieditfield(app.X7B_Panel, 'numeric');
            app.yEditField_10Label_2 = uilabel(app.X7B_Panel);
            app.yEditField_10Label_2.Text = 'y';
            app.accelerations_6_y = uieditfield(app.X7B_Panel, 'numeric');
            app.zEditField_10Label_2 = uilabel(app.X7B_Panel);
            app.zEditField_10Label_2.Text = 'z';
            app.accelerations_6_z = uieditfield(app.X7B_Panel, 'numeric');
            app.EditField_16 = uieditfield(app.X7B_Panel, 'text');
            app.EditField_16.Editable = 'off';
            app.EditField_16.Value = 'Accelerations';
            app.EditField_17 = uieditfield(app.X7B_Panel, 'text');
            app.EditField_17.Editable = 'off';
            app.EditField_17.Value = 'Angle velocity';
            app.EditField_18 = uieditfield(app.X7B_Panel, 'text');
            app.EditField_18.Editable = 'off';
            app.EditField_18.Value = 'Perspectives';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '保存数据';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '校准';
            app.motionEditFieldLabel = uilabel(app.UIFigure);
            app.motionEditFieldLabel.Text = 'motion';
            app.motionEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end