classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        Image                   matlab.ui.control.Image
        IMU4Panel               matlab.ui.container.Panel
        EditField_12            matlab.ui.control.EditField
        EditField_11            matlab.ui.control.EditField
        EditField_10            matlab.ui.control.EditField
        rollEditField_12        matlab.ui.control.NumericEditField
        rollEditField_12Label   matlab.ui.control.Label
        yawEditField_12         matlab.ui.control.NumericEditField
        yawEditField_12Label    matlab.ui.control.Label
        pitchEditField_12       matlab.ui.control.NumericEditField
        pitchEditField_12Label  matlab.ui.control.Label
        rollEditField_11        matlab.ui.control.NumericEditField
        rollEditField_11Label   matlab.ui.control.Label
        yawEditField_11         matlab.ui.control.NumericEditField
        yawEditField_11Label    matlab.ui.control.Label
        pitchEditField_11       matlab.ui.control.NumericEditField
        pitchEditField_11Label  matlab.ui.control.Label
        yawEditField_10         matlab.ui.control.NumericEditField
        yawEditField_10Label    matlab.ui.control.Label
        rollEditField_10        matlab.ui.control.NumericEditField
        rollEditField_10Label   matlab.ui.control.Label
        pitchEditField_10       matlab.ui.control.NumericEditField
        pitchEditField_10Label  matlab.ui.control.Label
        IMU3Panel               matlab.ui.container.Panel
        EditField_9             matlab.ui.control.EditField
        EditField_8             matlab.ui.control.EditField
        EditField_7             matlab.ui.control.EditField
        rollEditField_9         matlab.ui.control.NumericEditField
        rollEditField_9Label    matlab.ui.control.Label
        yawEditField_9          matlab.ui.control.NumericEditField
        yawEditField_9Label     matlab.ui.control.Label
        pitchEditField_9        matlab.ui.control.NumericEditField
        pitchEditField_9Label   matlab.ui.control.Label
        rollEditField_8         matlab.ui.control.NumericEditField
        rollEditField_8Label    matlab.ui.control.Label
        yawEditField_8          matlab.ui.control.NumericEditField
        yawEditField_8Label     matlab.ui.control.Label
        pitchEditField_8        matlab.ui.control.NumericEditField
        pitchEditField_8Label   matlab.ui.control.Label
        yawEditField_7          matlab.ui.control.NumericEditField
        yawEditField_7Label     matlab.ui.control.Label
        rollEditField_7         matlab.ui.control.NumericEditField
        rollEditField_7Label    matlab.ui.control.Label
        pitchEditField_7        matlab.ui.control.NumericEditField
        pitchEditField_7Label   matlab.ui.control.Label
        IMU2Panel               matlab.ui.container.Panel
        EditField_6             matlab.ui.control.EditField
        EditField_5             matlab.ui.control.EditField
        EditField_4             matlab.ui.control.EditField
        zEditField_4            matlab.ui.control.NumericEditField
        zEditField_4Label       matlab.ui.control.Label
        yEditField_4            matlab.ui.control.NumericEditField
        yEditField_4Label       matlab.ui.control.Label
        xEditField_4            matlab.ui.control.NumericEditField
        xEditField_4Label       matlab.ui.control.Label
        rollEditField_5         matlab.ui.control.NumericEditField
        rollEditField_5Label    matlab.ui.control.Label
        yawEditField_5          matlab.ui.control.NumericEditField
        yawEditField_5Label     matlab.ui.control.Label
        pitchEditField_5        matlab.ui.control.NumericEditField
        pitchEditField_5Label   matlab.ui.control.Label
        yawEditField_4          matlab.ui.control.NumericEditField
        yawEditField_4Label     matlab.ui.control.Label
        rollEditField_4         matlab.ui.control.NumericEditField
        rollEditField_4Label    matlab.ui.control.Label
        pitchEditField_4        matlab.ui.control.NumericEditField
        pitchEditField_4Label   matlab.ui.control.Label
        IMU1Panel               matlab.ui.container.Panel
        EditField_3             matlab.ui.control.EditField
        EditField_2             matlab.ui.control.EditField
        EditField               matlab.ui.control.EditField
        zEditField              matlab.ui.control.NumericEditField
        zEditFieldLabel         matlab.ui.control.Label
        yEditField              matlab.ui.control.NumericEditField
        yEditFieldLabel         matlab.ui.control.Label
        xEditField              matlab.ui.control.NumericEditField
        xEditFieldLabel         matlab.ui.control.Label
        zEditField_2            matlab.ui.control.NumericEditField
        zEditField_2Label       matlab.ui.control.Label
        yEditField_2            matlab.ui.control.NumericEditField
        yEditField_2Label       matlab.ui.control.Label
        xEditField_2            matlab.ui.control.NumericEditField
        xEditField_2Label       matlab.ui.control.Label
        yEditField_3            matlab.ui.control.NumericEditField
        yEditField_3Label       matlab.ui.control.Label
        zEditField_3            matlab.ui.control.NumericEditField
        zEditField_3Label       matlab.ui.control.Label
        xEditField_3            matlab.ui.control.NumericEditField
        xEditField_3Label       matlab.ui.control.Label
        stateEditField          matlab.ui.control.EditField
        stateEditFieldLabel     matlab.ui.control.Label
        Panel                   matlab.ui.container.Panel
        serial_state            matlab.ui.control.Lamp
        LampLabel               matlab.ui.control.Label
        SerialSwitch            matlab.ui.control.Switch
        SerialStopBit           matlab.ui.control.DropDown
        DropDownLabel           matlab.ui.control.Label
        SerialParity            matlab.ui.control.DropDown
        DropDown_2Label         matlab.ui.control.Label
        SerialBaud              matlab.ui.control.DropDown
        BaudDropDownLabel       matlab.ui.control.Label
        SerialPortNums          matlab.ui.control.DropDown
        COMDropDownLabel        matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialObject % Description
    end
    
    methods (Access = private)
        
    function results = dealdata(app)%读取数据
    app.stateEditField.Value="ok"
    for i=1:length(app.SerialObject.UserData)-3         %解码数据，arduino数据是以数组形式传出，读取为字符数组形式。
    if app.SerialObject.UserData(i)=="1"&&app.SerialObject.UserData(i+1)==":"&&app.SerialObject.UserData(i+2)==" "
        L1=0;
        while 1
            if ((i+3+L1)<40)
                if app.SerialObject.UserData(i+L1+3)~="$"
                    L1=L1+1;
                else
                    break;
                end
            else
                break;
            end
        end
            x1=str2num(app.SerialObject.UserData(i+3:i+3+L1-1));
        L2=0;
       while 1
            if ((i+4+L1+L2)<40)
                if app.SerialObject.UserData(i+L1+L2+4)~="$"
                    L2=L2+1;
                else
                    break;
                end
            else
                break;
            end
        end
            y1=str2num(app.SerialObject.UserData(i+4+L1:i+4+L1+L2-1));
        L3=0;
        while 1
            if ((i+5+L1+L2+L3)<40)
                if app.SerialObject.UserData(i+L1+L2+L3+5)~="$"
                    L3=L3+1;
                else
                    break;
                end
            else
                break;
            end
        end
            z1=str2num(app.SerialObject.UserData(i+5+L1+L2:i+5+L1+L2+L3-1));  
    end
    end
app.xEditField_3.Value=x1;
app.zEditField_3.Value=y1;
app.yEditField_3.Value=z1;
app.ArtificialHorizon.Pitch=-x1;
app.ArtificialHorizon.Roll=y1;
app.HeadingIndicator.Heading=z1;
end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end  
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
                           com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="9600";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
            end  
            
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
                        if(value=='打开')
                    serialComName = 'COM14';
                    serialBaudrate = 9600;
                    serialDataBit = 8;
                    serialCheckBit = 'none';
                    serialStopBit = 1;
                try
%                      delete(serialport)%注销系统之前已经打开的串口资源
%                      app.SerialObject=serialport( ...
%                      app.SerialPortNums.Value, ...  %设置端口号
%                      str2double(app.SerialBaud.Value),...%设置波特率
%                     'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
%                     'Parity',app.SerialParity.Value);%设置校验位

                    app.SerialObject=serialport(serialComName,serialBaudrate,"Parity",serialCheckBit,"DataBits",serialDataBit,"StopBits",serialStopBit,"Timeout",1);
                    configureCallback(app.SerialObject,"byte",40,@readSerialData)%设置回调函数readSerialData 接受串口返回的数据 
                    app.serial_state.Color=[0,1,0];%绿色  将指示灯打开
                catch
                     errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                     app.SerialSwitch.Value='关闭';
                     return
                end
            elseif (value=='关闭')
                 delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                 app.serial_state.Color=[1,1,1];%白色  将指示灯关闭
            end
            %串口接受数据的回调函数
            function readSerialData(src,event)
              app.SerialObject.UserData=read(app.SerialObject,40,"char");
              dealdata(app);%处理接收到的串口数据，并显示在组件上
           end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '串口选择';
            app.COMDropDownLabel = uilabel(app.Panel);
            app.COMDropDownLabel.Text = 'COM';
            app.SerialPortNums = uidropdown(app.Panel);
            app.SerialPortNums.Items = {'', ''};
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.SerialPortNums.Value = '';
            app.BaudDropDownLabel = uilabel(app.Panel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.SerialBaud = uidropdown(app.Panel);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '2400';
            app.DropDown_2Label = uilabel(app.Panel);
            app.DropDown_2Label.Text = '校验位';
            app.SerialParity = uidropdown(app.Panel);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Text = '停止位';
            app.SerialStopBit = uidropdown(app.Panel);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.SerialSwitch = uiswitch(app.Panel, 'slider');
            app.SerialSwitch.Items = {'打开', '关闭'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = '关闭';
            app.LampLabel = uilabel(app.Panel);
            app.LampLabel.Text = 'Lamp';
            app.serial_state = uilamp(app.Panel);
            app.stateEditFieldLabel = uilabel(app.UIFigure);
            app.stateEditFieldLabel.Text = 'state';
            app.stateEditField = uieditfield(app.UIFigure, 'text');
            app.IMU1Panel = uipanel(app.UIFigure);
            app.IMU1Panel.Title = 'IMU1';
            app.xEditField_3Label = uilabel(app.IMU1Panel);
            app.xEditField_3Label.Text = 'x';
            app.xEditField_3 = uieditfield(app.IMU1Panel, 'numeric');
            app.zEditField_3Label = uilabel(app.IMU1Panel);
            app.zEditField_3Label.Text = 'z';
            app.zEditField_3 = uieditfield(app.IMU1Panel, 'numeric');
            app.yEditField_3Label = uilabel(app.IMU1Panel);
            app.yEditField_3Label.Text = 'y';
            app.yEditField_3 = uieditfield(app.IMU1Panel, 'numeric');
            app.xEditField_2Label = uilabel(app.IMU1Panel);
            app.xEditField_2Label.Text = 'x';
            app.xEditField_2 = uieditfield(app.IMU1Panel, 'numeric');
            app.yEditField_2Label = uilabel(app.IMU1Panel);
            app.yEditField_2Label.Text = 'y';
            app.yEditField_2 = uieditfield(app.IMU1Panel, 'numeric');
            app.zEditField_2Label = uilabel(app.IMU1Panel);
            app.zEditField_2Label.Text = 'z';
            app.zEditField_2 = uieditfield(app.IMU1Panel, 'numeric');
            app.xEditFieldLabel = uilabel(app.IMU1Panel);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.IMU1Panel, 'numeric');
            app.yEditFieldLabel = uilabel(app.IMU1Panel);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.IMU1Panel, 'numeric');
            app.zEditFieldLabel = uilabel(app.IMU1Panel);
            app.zEditFieldLabel.Text = 'z';
            app.zEditField = uieditfield(app.IMU1Panel, 'numeric');
            app.EditField = uieditfield(app.IMU1Panel, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Accelerations';
            app.EditField_2 = uieditfield(app.IMU1Panel, 'text');
            app.EditField_2.Editable = 'off';
            app.EditField_2.Value = 'Angle velocity';
            app.EditField_3 = uieditfield(app.IMU1Panel, 'text');
            app.EditField_3.Editable = 'off';
            app.EditField_3.Value = 'Perspectives';
            app.IMU2Panel = uipanel(app.UIFigure);
            app.IMU2Panel.Title = 'IMU2';
            app.pitchEditField_4Label = uilabel(app.IMU2Panel);
            app.pitchEditField_4Label.Text = 'pitch';
            app.pitchEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.rollEditField_4Label = uilabel(app.IMU2Panel);
            app.rollEditField_4Label.Text = 'roll';
            app.rollEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.yawEditField_4Label = uilabel(app.IMU2Panel);
            app.yawEditField_4Label.Text = 'yaw';
            app.yawEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.pitchEditField_5Label = uilabel(app.IMU2Panel);
            app.pitchEditField_5Label.Text = 'pitch';
            app.pitchEditField_5 = uieditfield(app.IMU2Panel, 'numeric');
            app.yawEditField_5Label = uilabel(app.IMU2Panel);
            app.yawEditField_5Label.Text = 'yaw';
            app.yawEditField_5 = uieditfield(app.IMU2Panel, 'numeric');
            app.rollEditField_5Label = uilabel(app.IMU2Panel);
            app.rollEditField_5Label.Text = 'roll';
            app.rollEditField_5 = uieditfield(app.IMU2Panel, 'numeric');
            app.xEditField_4Label = uilabel(app.IMU2Panel);
            app.xEditField_4Label.Text = 'x';
            app.xEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.yEditField_4Label = uilabel(app.IMU2Panel);
            app.yEditField_4Label.Text = 'y';
            app.yEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.zEditField_4Label = uilabel(app.IMU2Panel);
            app.zEditField_4Label.Text = 'z';
            app.zEditField_4 = uieditfield(app.IMU2Panel, 'numeric');
            app.EditField_4 = uieditfield(app.IMU2Panel, 'text');
            app.EditField_4.Editable = 'off';
            app.EditField_4.Value = 'Accelerations';
            app.EditField_5 = uieditfield(app.IMU2Panel, 'text');
            app.EditField_5.Editable = 'off';
            app.EditField_5.Value = 'Angle velocity';
            app.EditField_6 = uieditfield(app.IMU2Panel, 'text');
            app.EditField_6.Editable = 'off';
            app.EditField_6.Value = 'Perspectives';
            app.IMU3Panel = uipanel(app.UIFigure);
            app.IMU3Panel.Title = 'IMU3';
            app.pitchEditField_7Label = uilabel(app.IMU3Panel);
            app.pitchEditField_7Label.Text = 'pitch';
            app.pitchEditField_7 = uieditfield(app.IMU3Panel, 'numeric');
            app.rollEditField_7Label = uilabel(app.IMU3Panel);
            app.rollEditField_7Label.Text = 'roll';
            app.rollEditField_7 = uieditfield(app.IMU3Panel, 'numeric');
            app.yawEditField_7Label = uilabel(app.IMU3Panel);
            app.yawEditField_7Label.Text = 'yaw';
            app.yawEditField_7 = uieditfield(app.IMU3Panel, 'numeric');
            app.pitchEditField_8Label = uilabel(app.IMU3Panel);
            app.pitchEditField_8Label.Text = 'pitch';
            app.pitchEditField_8 = uieditfield(app.IMU3Panel, 'numeric');
            app.yawEditField_8Label = uilabel(app.IMU3Panel);
            app.yawEditField_8Label.Text = 'yaw';
            app.yawEditField_8 = uieditfield(app.IMU3Panel, 'numeric');
            app.rollEditField_8Label = uilabel(app.IMU3Panel);
            app.rollEditField_8Label.Text = 'roll';
            app.rollEditField_8 = uieditfield(app.IMU3Panel, 'numeric');
            app.pitchEditField_9Label = uilabel(app.IMU3Panel);
            app.pitchEditField_9Label.Text = 'pitch';
            app.pitchEditField_9 = uieditfield(app.IMU3Panel, 'numeric');
            app.yawEditField_9Label = uilabel(app.IMU3Panel);
            app.yawEditField_9Label.Text = 'yaw';
            app.yawEditField_9 = uieditfield(app.IMU3Panel, 'numeric');
            app.rollEditField_9Label = uilabel(app.IMU3Panel);
            app.rollEditField_9Label.Text = 'roll';
            app.rollEditField_9 = uieditfield(app.IMU3Panel, 'numeric');
            app.EditField_7 = uieditfield(app.IMU3Panel, 'text');
            app.EditField_7.Editable = 'off';
            app.EditField_7.Value = 'Accelerations';
            app.EditField_8 = uieditfield(app.IMU3Panel, 'text');
            app.EditField_8.Editable = 'off';
            app.EditField_8.Value = 'Angle velocity';
            app.EditField_9 = uieditfield(app.IMU3Panel, 'text');
            app.EditField_9.Editable = 'off';
            app.EditField_9.Value = 'Perspectives';
            app.IMU4Panel = uipanel(app.UIFigure);
            app.IMU4Panel.Title = 'IMU4';
            app.pitchEditField_10Label = uilabel(app.IMU4Panel);
            app.pitchEditField_10Label.Text = 'pitch';
            app.pitchEditField_10 = uieditfield(app.IMU4Panel, 'numeric');
            app.rollEditField_10Label = uilabel(app.IMU4Panel);
            app.rollEditField_10Label.Text = 'roll';
            app.rollEditField_10 = uieditfield(app.IMU4Panel, 'numeric');
            app.yawEditField_10Label = uilabel(app.IMU4Panel);
            app.yawEditField_10Label.Text = 'yaw';
            app.yawEditField_10 = uieditfield(app.IMU4Panel, 'numeric');
            app.pitchEditField_11Label = uilabel(app.IMU4Panel);
            app.pitchEditField_11Label.Text = 'pitch';
            app.pitchEditField_11 = uieditfield(app.IMU4Panel, 'numeric');
            app.yawEditField_11Label = uilabel(app.IMU4Panel);
            app.yawEditField_11Label.Text = 'yaw';
            app.yawEditField_11 = uieditfield(app.IMU4Panel, 'numeric');
            app.rollEditField_11Label = uilabel(app.IMU4Panel);
            app.rollEditField_11Label.Text = 'roll';
            app.rollEditField_11 = uieditfield(app.IMU4Panel, 'numeric');
            app.pitchEditField_12Label = uilabel(app.IMU4Panel);
            app.pitchEditField_12Label.Text = 'pitch';
            app.pitchEditField_12 = uieditfield(app.IMU4Panel, 'numeric');
            app.yawEditField_12Label = uilabel(app.IMU4Panel);
            app.yawEditField_12Label.Text = 'yaw';
            app.yawEditField_12 = uieditfield(app.IMU4Panel, 'numeric');
            app.rollEditField_12Label = uilabel(app.IMU4Panel);
            app.rollEditField_12Label.Text = 'roll';
            app.rollEditField_12 = uieditfield(app.IMU4Panel, 'numeric');
            app.EditField_10 = uieditfield(app.IMU4Panel, 'text');
            app.EditField_10.Editable = 'off';
            app.EditField_10.Value = 'Accelerations';
            app.EditField_11 = uieditfield(app.IMU4Panel, 'text');
            app.EditField_11.Editable = 'off';
            app.EditField_11.Value = 'Angle velocity';
            app.EditField_12 = uieditfield(app.IMU4Panel, 'text');
            app.EditField_12.Editable = 'off';
            app.EditField_12.Value = 'Perspectives';
            app.Image = uiimage(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end