classdef UWB_Data_Display < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        KT0169293498NULLLO245544143Label  matlab.ui.control.Label
        ProtocolsLabel            matlab.ui.control.Label
        fHzLabel                  matlab.ui.control.Label
        Hz                        matlab.ui.control.NumericEditField
        ViewPanel                 matlab.ui.container.Panel
        SpanLabel                 matlab.ui.control.Label
        Span                      matlab.ui.control.NumericEditField
        viewButton                matlab.ui.control.Button
        Switch                    matlab.ui.control.ToggleSwitch
        TimeLabel                 matlab.ui.control.Label
        Time_Text                 matlab.ui.control.NumericEditField
        Axis_LimitPanel           matlab.ui.container.Panel
        ResetButton               matlab.ui.control.Button
        SetButton_2               matlab.ui.control.Button
        MaxLabel                  matlab.ui.control.Label
        MinLabel                  matlab.ui.control.Label
        BS4Label_2                matlab.ui.control.Label
        BS3Label_2                matlab.ui.control.Label
        BS2Label_2                matlab.ui.control.Label
        BS1Label_3                matlab.ui.control.Label
        BS4_Y_Max                 matlab.ui.control.NumericEditField
        BS4_Y_Min                 matlab.ui.control.NumericEditField
        BS3_Y_Max                 matlab.ui.control.NumericEditField
        BS3_Y_Min                 matlab.ui.control.NumericEditField
        BS2_Y_Max                 matlab.ui.control.NumericEditField
        BS2_Y_Min                 matlab.ui.control.NumericEditField
        BS1_Y_Max                 matlab.ui.control.NumericEditField
        BS1_Y_Min                 matlab.ui.control.NumericEditField
        AuthorLabel               matlab.ui.control.Label
        Label_3                   matlab.ui.control.Label
        Label_2                   matlab.ui.control.Label
        BS_AxismPanel             matlab.ui.container.Panel
        SetButton                 matlab.ui.control.Button
        BS1Label                  matlab.ui.control.Label
        YLabel                    matlab.ui.control.Label
        XLabel                    matlab.ui.control.Label
        BS1_Y                     matlab.ui.control.NumericEditField
        BS1_X                     matlab.ui.control.NumericEditField
        BS4Label                  matlab.ui.control.Label
        BS3Label                  matlab.ui.control.Label
        BS2Label                  matlab.ui.control.Label
        BS4_X                     matlab.ui.control.NumericEditField
        BS4_Y                     matlab.ui.control.NumericEditField
        BS3_X                     matlab.ui.control.NumericEditField
        BS3_Y                     matlab.ui.control.NumericEditField
        BS2_X                     matlab.ui.control.NumericEditField
        BS2_Y                     matlab.ui.control.NumericEditField
        EquipmentParametersPanel  matlab.ui.container.Panel
        test_txt                  matlab.ui.control.EditField
        serial_state              matlab.ui.control.Lamp
        StatusesLabel             matlab.ui.control.Label
        SerialSwitch              matlab.ui.control.Switch
        SerialParity              matlab.ui.control.DropDown
        ParityLabel               matlab.ui.control.Label
        SerialBaud                matlab.ui.control.DropDown
        BaudDropDownLabel         matlab.ui.control.Label
        SerialStopBit             matlab.ui.control.DropDown
        StopLabel                 matlab.ui.control.Label
        SerialPortNums            matlab.ui.control.DropDown
        COMDropDownLabel          matlab.ui.control.Label
        UWBDataDisplayToolLabel   matlab.ui.control.Label
        Label_4                   matlab.ui.control.Label
        axis                      matlab.ui.control.UIAxes
        uwb_d0                    matlab.ui.control.UIAxes
        uwb_d1                    matlab.ui.control.UIAxes
        uwb_d2                    matlab.ui.control.UIAxes
        uwb_d3                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        uwbRXFlag % Description
        uwbRXBuffer=zeros(100);%数据缓存
        uwbRXCnt % Description
        SerialObject % Description
        straxis=char(zeros(3,10)) % 存放x、y、z坐标的字符串数组
        strrange=char(zeros(4,10))  % 存放基站1~4的距离字符
        virtual_time=1:10000 % Description
        uwb_d0_temp=zeros(10000)
        uwb_d1_temp=zeros(10000)
        uwb_d2_temp=zeros(10000)
        uwb_d3_temp=zeros(10000)
        uwb_axis=zeros(3,10000) % Description
        uwb_range=zeros(4,1) % Description
        vtime_index=1 % Description
        while_time=1;
        err_time=30
        t_now; % Description
        t_last; % Description
        time_gap;
        freq;
    end
    
    methods (Access = private)
        
        function Deal_serial_data(app) %处理串口数据，逐帧分析
            if(app.uwbRXFlag==1)
                return;
            end
            app.uwbRXBuffer(app.uwbRXCnt)=app.SerialObject.UserData;
            app.uwbRXCnt=app.uwbRXCnt+1;
            if(app.uwbRXBuffer(1)~='$')
                app.uwbRXCnt=1;
                app.uwbRXFlag=0;
                return;
            end
            if(app.uwbRXBuffer(app.uwbRXCnt-1)~=']')
                return;
            else
                if(app.uwbRXBuffer(2)=='n')
                    app.uwbRXCnt=1;
                    app.uwbRXFlag=0;
                    return;
                else
                    app.uwbRXFlag=1;
                end
            end
            Uwb_data_analysis(app);
        end
        
        function Uwb_data_analysis(app)
            if(app.uwbRXFlag==1)
                app.t_last=app.t_now;
                app.t_now=clock;
%                 app.last_fen.Value=app.t_last(5);
%                 app.last_miao.Value=app.t_last(6);
%                 app.now_fen.Value=app.t_now(5);
%                 app.now_miao.Value=app.t_now(6);
                app.time_gap=app.t_now(6)-app.t_last(6);
                app.freq=1/app.time_gap;
                if(app.freq>0)
                app.Hz.Value=app.freq;
                end
                read_index=1; %数据索引
                str_index=1;
                straxis_type_index=1;
                strrange_type_index=1;
                while(app.uwbRXBuffer(read_index)~=',')
                    read_index=read_index+1;
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                read_index=read_index+1;
                
                while(app.uwbRXBuffer(read_index)~=',')                       
                    app.strrange(strrange_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                strrange_type_index=strrange_type_index+1;
                read_index=read_index+1;
                
                while(app.uwbRXBuffer(read_index)~=',')                       
                    app.strrange(strrange_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                strrange_type_index=strrange_type_index+1;
                read_index=read_index+1;    
                                
                while(app.uwbRXBuffer(read_index)~=',')                       
                    app.strrange(strrange_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                strrange_type_index=strrange_type_index+1;
                read_index=read_index+1;
                
                while(app.uwbRXBuffer(read_index)~=',')                       
                    app.strrange(strrange_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                read_index=read_index+1;
                
                while(app.uwbRXBuffer(read_index)~='[')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    read_index=read_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                read_index=read_index+1;
                while(app.uwbRXBuffer(read_index)~=',')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.straxis(straxis_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                straxis_type_index=straxis_type_index+1;
                read_index=read_index+1;

                while(app.uwbRXBuffer(read_index)~=',')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.straxis(straxis_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                straxis_type_index=straxis_type_index+1;
                read_index=read_index+1;
                while(app.uwbRXBuffer(read_index)~=']')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.straxis(straxis_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                app.uwbRXFlag=0;
                app.uwbRXCnt=1;
                app.uwb_axis(1,app.vtime_index)=str2double(app.straxis(1,:));
                app.uwb_axis(2,app.vtime_index)=str2double(app.straxis(2,:));
                app.uwb_axis(3,app.vtime_index)=str2double(app.straxis(3,:));
            
                app.uwb_range(1)=str2double(app.strrange(1,:));
                app.uwb_range(2)=str2double(app.strrange(2,:));
                app.uwb_range(3)=str2double(app.strrange(3,:));
                app.uwb_range(4)=str2double(app.strrange(4,:));
                
                app.uwb_d0_temp(app.vtime_index)=app.uwb_range(1);
                app.uwb_d1_temp(app.vtime_index)=app.uwb_range(2);
                app.uwb_d2_temp(app.vtime_index)=app.uwb_range(3);
                app.uwb_d3_temp(app.vtime_index)=app.uwb_range(4);
                
                plot(app.axis,app.uwb_axis(1,app.vtime_index),app.uwb_axis(2,app.vtime_index),'.','Color','b','MarkerSize',8);
                hold (app.axis,'on');
                plot(app.axis,app.uwb_axis(1,1:app.vtime_index),app.uwb_axis(2,1:app.vtime_index),'-','Color','r','LineWidth',0.5);
                hold (app.axis,'on');
                plot(app.uwb_d0,app.virtual_time(1:app.vtime_index),app.uwb_d0_temp(1:app.vtime_index),'-','Color','b','LineWidth',1);
                hold (app.uwb_d0,'on');
                plot(app.uwb_d1,app.virtual_time(1:app.vtime_index),app.uwb_d1_temp(1:app.vtime_index),'-','Color','r','LineWidth',1);
                hold (app.uwb_d1,'on');
                plot(app.uwb_d2,app.virtual_time(1:app.vtime_index),app.uwb_d2_temp(1:app.vtime_index),'-','Color','m','LineWidth',1);
                hold (app.uwb_d2,'on');
                plot(app.uwb_d3,app.virtual_time(1:app.vtime_index),app.uwb_d3_temp(1:app.vtime_index),'-','Color','k','LineWidth',1);
                hold (app.uwb_d3,'on');
                if(app.vtime_index<10000)
                    app.vtime_index=app.vtime_index+1;
                else
                    app.vtime_index=1;
                    hold (app.uwb_d0,'off');
                    hold (app.uwb_d1,'off');
                    hold (app.uwb_d2,'off');
                    hold (app.uwb_d3,'off');
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        app.virtual_time=1:10000;
        app.uwbRXCnt=1;
        app.uwbRXFlag=0;
        app.vtime_index=1;
        app.t_now=clock;
        com_list=serialportlist("all");%找到可以用的串口号 
        hold(app.axis,"on");
        plot(app.axis,app.BS1_X.Value,app.BS1_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS2_X.Value,app.BS2_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS3_X.Value,app.BS3_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS4_X.Value,app.BS4_Y.Value,'.','MarkerSize',20,"Color",'r');
        text(app.axis,app.BS1_X.Value,app.BS1_Y.Value,"BS1",'FontSize',14,"FontWeight","bold");
        text(app.axis,app.BS2_X.Value,app.BS2_Y.Value,"BS2","FontSize",14,"FontWeight","bold");
        text(app.axis,app.BS3_X.Value,app.BS3_Y.Value,"BS3","FontSize",14,"FontWeight","bold");
        text(app.axis,app.BS4_X.Value,app.BS4_Y.Value,"BS4","FontSize",14,"FontWeight","bold");
        axis(app.axis,[(app.BS1_X.Value-(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS2_X.Value+(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS1_Y.Value-(app.BS4_Y.Value-app.BS1_Y.Value)*0.1) (app.BS4_Y.Value+(app.BS4_Y.Value-app.BS1_Y.Value)*0.1)]);

        try
            app.SerialPortNums.Items=com_list;
            app.SerialBaud.Value="115200";  
        catch
            app.portData.PortName='null';
            errordlg('没有搜索到任何可用端口');
        end  
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
           com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
           end
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
            app.test_txt.Value='start';
            if(value=="Open")
                try
%                      delete(serialport)%注销系统之前已经打开的串口资源
                     app.SerialObject=serialport( ...
                     app.SerialPortNums.Value, ...  %设置端口号
                     str2double(app.SerialBaud.Value),...%设置波特率
                    'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
                    'Parity',app.SerialParity.Value);%设置校验位
                    configureCallback(app.SerialObject,"byte",1,@readSerialData)%设置回调函数readSerialData 接受串口返回的数据 
                    app.serial_state.Color=[0,1,0];%绿色  将指示灯打开
                catch
                     errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                     app.SerialSwitch.Value='Close';
                     return
                end
            elseif (value=="Close")
                 delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                 app.serial_state.Color=[1,1,1];%白色  将指示灯关闭
            end
            %串口接受数据的回调函数
            function readSerialData(src,event)
              app.SerialObject.UserData=read(app.SerialObject,1,"uint8");

              Deal_serial_data(app);%处理接收到的串口数据
           end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.uwbRXCnt=1;
            app.uwbRXFlag=0;
            app.vtime_index=1;
            hold (app.axis,'off');
            hold (app.uwb_d0,'off');
            hold (app.uwb_d1,'off');
            hold (app.uwb_d2,'off');
            hold (app.uwb_d3,'off');
            axis(app.axis,[(app.BS1_X.Value-(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS2_X.Value+(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS1_Y.Value-(app.BS4_Y.Value-app.BS1_Y.Value)*0.1) (app.BS4_Y.Value+(app.BS4_Y.Value-app.BS1_Y.Value)*0.1)]);
            plot(app.axis,app.BS1_X.Value,app.BS1_Y.Value,'.','MarkerSize',20,"Color",'r');
            hold(app.axis,'on');
            plot(app.axis,app.BS2_X.Value,app.BS2_Y.Value,'.','MarkerSize',20,"Color",'r');
            plot(app.axis,app.BS3_X.Value,app.BS3_Y.Value,'.','MarkerSize',20,"Color",'r');
            plot(app.axis,app.BS4_X.Value,app.BS4_Y.Value,'.','MarkerSize',20,"Color",'r');
            text(app.axis,app.BS1_X.Value,app.BS1_Y.Value,"BS1",'FontSize',14,"FontWeight","bold");
            text(app.axis,app.BS2_X.Value,app.BS2_Y.Value,"BS2","FontSize",14,"FontWeight","bold");
            text(app.axis,app.BS3_X.Value,app.BS3_Y.Value,"BS3","FontSize",14,"FontWeight","bold");
            text(app.axis,app.BS4_X.Value,app.BS4_Y.Value,"BS4","FontSize",14,"FontWeight","bold");
        end

        % Button pushed function: SetButton_2
        function SetButton_2Pushed(app, event)
            axis(app.axis,[(app.BS1_X.Value-(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS2_X.Value+(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS1_Y.Value-(app.BS4_Y.Value-app.BS1_Y.Value)*0.1) (app.BS4_Y.Value+(app.BS4_Y.Value-app.BS1_Y.Value)*0.1)]);
            
            ylim(app.uwb_d0,[app.BS1_Y_Min.Value app.BS1_Y_Max.Value]);
            ylim(app.uwb_d1,[app.BS2_Y_Min.Value app.BS2_Y_Max.Value]);
            ylim(app.uwb_d2,[app.BS3_Y_Min.Value app.BS3_Y_Max.Value]);
            ylim(app.uwb_d3,[app.BS4_Y_Min.Value app.BS4_Y_Max.Value]);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            axis(app.axis,'auto');
            axis(app.uwb_d0,'auto');
            axis(app.uwb_d1,'auto');
            axis(app.uwb_d2,'auto');
            axis(app.uwb_d3,'auto');
        end

        % Button pushed function: viewButton
        function viewButtonPushed(app, event)
            if(app.Switch.Value=="Now")
                xlim(app.uwb_d0,[app.vtime_index-app.Span.Value app.vtime_index+app.Span.Value]);
                xlim(app.uwb_d1,[app.vtime_index-app.Span.Value app.vtime_index+app.Span.Value]);
                xlim(app.uwb_d2,[app.vtime_index-app.Span.Value app.vtime_index+app.Span.Value]);
                xlim(app.uwb_d3,[app.vtime_index-app.Span.Value app.vtime_index+app.Span.Value]);
            elseif(app.Switch.Value=="Specify")
                xlim(app.uwb_d0,[app.Time_Text.Value-app.Span.Value app.Time_Text.Value+app.Span.Value]);
                xlim(app.uwb_d1,[app.Time_Text.Value-app.Span.Value app.Time_Text.Value+app.Span.Value]);
                xlim(app.uwb_d2,[app.Time_Text.Value-app.Span.Value app.Time_Text.Value+app.Span.Value]);
                xlim(app.uwb_d3,[app.Time_Text.Value-app.Span.Value app.Time_Text.Value+app.Span.Value]);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.uwb_d3 = uiaxes(app.UIFigure);
            title(app.uwb_d3, 'BS4')
            xlabel(app.uwb_d3, 'time')
            ylabel(app.uwb_d3, 'distance(m)')
            zlabel(app.uwb_d3, 'Z')
            app.uwb_d2 = uiaxes(app.UIFigure);
            title(app.uwb_d2, 'BS3')
            xlabel(app.uwb_d2, 'time')
            ylabel(app.uwb_d2, 'distance(m)')
            zlabel(app.uwb_d2, 'Z')
            app.uwb_d1 = uiaxes(app.UIFigure);
            title(app.uwb_d1, 'BS2')
            xlabel(app.uwb_d1, 'time')
            ylabel(app.uwb_d1, 'distance(m)')
            zlabel(app.uwb_d1, 'Z')
            app.uwb_d0 = uiaxes(app.UIFigure);
            title(app.uwb_d0, 'BS1')
            xlabel(app.uwb_d0, 'time')
            ylabel(app.uwb_d0, 'distance(m)')
            zlabel(app.uwb_d0, 'Z')
            app.axis = uiaxes(app.UIFigure);
            title(app.axis, 'Axis')
            xlabel(app.axis, 'X')
            ylabel(app.axis, 'Y')
            zlabel(app.axis, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '曹宇辉';
            app.UWBDataDisplayToolLabel = uilabel(app.UIFigure);
            app.UWBDataDisplayToolLabel.Text = 'UWB Data Display Tool';
            app.EquipmentParametersPanel = uipanel(app.UIFigure);
            app.EquipmentParametersPanel.Title = 'Equipment Parameters';
            app.COMDropDownLabel = uilabel(app.EquipmentParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.SerialPortNums = uidropdown(app.EquipmentParametersPanel);
            app.SerialPortNums.Items = {'', ''};
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.SerialPortNums.Value = '';
            app.StopLabel = uilabel(app.EquipmentParametersPanel);
            app.StopLabel.Text = 'Stop';
            app.SerialStopBit = uidropdown(app.EquipmentParametersPanel);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.BaudDropDownLabel = uilabel(app.EquipmentParametersPanel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.SerialBaud = uidropdown(app.EquipmentParametersPanel);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '2400';
            app.ParityLabel = uilabel(app.EquipmentParametersPanel);
            app.ParityLabel.Text = 'Parity';
            app.SerialParity = uidropdown(app.EquipmentParametersPanel);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.SerialSwitch = uiswitch(app.EquipmentParametersPanel, 'slider');
            app.SerialSwitch.Items = {'Open', 'Close'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = 'Close';
            app.StatusesLabel = uilabel(app.EquipmentParametersPanel);
            app.StatusesLabel.Text = 'Statuses';
            app.serial_state = uilamp(app.EquipmentParametersPanel);
            app.test_txt = uieditfield(app.EquipmentParametersPanel, 'text');
            app.BS_AxismPanel = uipanel(app.UIFigure);
            app.BS_AxismPanel.Title = 'BS_Axis(m)';
            app.BS2_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2_X.Value = 13.52;
            app.BS3_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS3_Y.Value = 8.35;
            app.BS3_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS3_X.Value = 13.52;
            app.BS4_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS4_Y.Value = 8.35;
            app.BS4_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2Label = uilabel(app.BS_AxismPanel);
            app.BS2Label.Text = 'BS2';
            app.BS3Label = uilabel(app.BS_AxismPanel);
            app.BS3Label.Text = 'BS3';
            app.BS4Label = uilabel(app.BS_AxismPanel);
            app.BS4Label.Text = 'BS4';
            app.BS1_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS1_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.XLabel = uilabel(app.BS_AxismPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.BS_AxismPanel);
            app.YLabel.Text = 'Y';
            app.BS1Label = uilabel(app.BS_AxismPanel);
            app.BS1Label.Text = 'BS1';
            app.SetButton = uibutton(app.BS_AxismPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '南京航空航天大学自动化学院';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '测控技术与仪器专业';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author：';
            app.Axis_LimitPanel = uipanel(app.UIFigure);
            app.Axis_LimitPanel.Title = 'Axis_Limit';
            app.BS1_Y_Min = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS1_Y_Max = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS2_Y_Min = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS2_Y_Max = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS3_Y_Min = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS3_Y_Max = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS4_Y_Min = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS4_Y_Max = uieditfield(app.Axis_LimitPanel, 'numeric');
            app.BS1Label_3 = uilabel(app.Axis_LimitPanel);
            app.BS1Label_3.Text = 'BS1';
            app.BS2Label_2 = uilabel(app.Axis_LimitPanel);
            app.BS2Label_2.Text = 'BS2';
            app.BS3Label_2 = uilabel(app.Axis_LimitPanel);
            app.BS3Label_2.Text = 'BS3';
            app.BS4Label_2 = uilabel(app.Axis_LimitPanel);
            app.BS4Label_2.Text = 'BS4';
            app.MinLabel = uilabel(app.Axis_LimitPanel);
            app.MinLabel.Text = 'Min';
            app.MaxLabel = uilabel(app.Axis_LimitPanel);
            app.MaxLabel.Text = 'Max';
            app.SetButton_2 = uibutton(app.Axis_LimitPanel, 'push');
            app.SetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SetButton_2Pushed, true);
            app.SetButton_2.Text = 'Set';
            app.ResetButton = uibutton(app.Axis_LimitPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.ViewPanel = uipanel(app.UIFigure);
            app.ViewPanel.Title = 'View';
            app.Time_Text = uieditfield(app.ViewPanel, 'numeric');
            app.TimeLabel = uilabel(app.ViewPanel);
            app.TimeLabel.Text = 'Time';
            app.Switch = uiswitch(app.ViewPanel, 'toggle');
            app.Switch.Items = {'Now', 'Specify'};
            app.Switch.Value = 'Now';
            app.viewButton = uibutton(app.ViewPanel, 'push');
            app.viewButton.ButtonPushedFcn = createCallbackFcn(app, @viewButtonPushed, true);
            app.viewButton.Text = 'view';
            app.Span = uieditfield(app.ViewPanel, 'numeric');
            app.Span.Value = 10;
            app.SpanLabel = uilabel(app.ViewPanel);
            app.SpanLabel.Text = 'Span';
            app.Hz = uieditfield(app.UIFigure, 'numeric');
            app.fHzLabel = uilabel(app.UIFigure);
            app.fHzLabel.Text = 'f(Hz)';
            app.ProtocolsLabel = uilabel(app.UIFigure);
            app.ProtocolsLabel.Text = 'Protocols:';
            app.KT0169293498NULLLO245544143Label = uilabel(app.UIFigure);
            app.KT0169293498NULLLO245544143Label.Text = '$KT0,1.69,2.93,4.98,NULL,LO=[-2.45,5.44,1.43]';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWB_Data_Display

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end