classdef UWB_ODOM_Fusion < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        BS3Label_2                     matlab.ui.control.Label
        BS4Label_5                     matlab.ui.control.Label
        BS2Label_2                     matlab.ui.control.Label
        BS1Label_2                     matlab.ui.control.Label
        BS4Label_2                     matlab.ui.control.Label
        anchor2                        matlab.ui.control.Lamp
        anchor1                        matlab.ui.control.Lamp
        anchor3                        matlab.ui.control.Lamp
        anchor4                        matlab.ui.control.Lamp
        fHzLabel                       matlab.ui.control.Label
        Hz                             matlab.ui.control.NumericEditField
        AuthorLabel                    matlab.ui.control.Label
        Label_3                        matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        BS_AxismPanel                  matlab.ui.container.Panel
        SetButton                      matlab.ui.control.Button
        BS1Label                       matlab.ui.control.Label
        YLabel                         matlab.ui.control.Label
        XLabel                         matlab.ui.control.Label
        BS1_Y                          matlab.ui.control.NumericEditField
        BS1_X                          matlab.ui.control.NumericEditField
        BS4Label                       matlab.ui.control.Label
        BS3Label                       matlab.ui.control.Label
        BS2Label                       matlab.ui.control.Label
        BS4_X                          matlab.ui.control.NumericEditField
        BS4_Y                          matlab.ui.control.NumericEditField
        BS3_X                          matlab.ui.control.NumericEditField
        BS3_Y                          matlab.ui.control.NumericEditField
        BS2_X                          matlab.ui.control.NumericEditField
        BS2_Y                          matlab.ui.control.NumericEditField
        EquipmentParametersPanel       matlab.ui.container.Panel
        test_txt                       matlab.ui.control.EditField
        serial_state                   matlab.ui.control.Lamp
        StatusesLabel                  matlab.ui.control.Label
        SerialSwitch                   matlab.ui.control.Switch
        SerialParity                   matlab.ui.control.DropDown
        ParityLabel                    matlab.ui.control.Label
        SerialBaud                     matlab.ui.control.DropDown
        BaudDropDownLabel              matlab.ui.control.Label
        SerialStopBit                  matlab.ui.control.DropDown
        StopLabel                      matlab.ui.control.Label
        SerialPortNums                 matlab.ui.control.DropDown
        COMDropDownLabel               matlab.ui.control.Label
        UWBFusionDataDisplayToolLabel  matlab.ui.control.Label
        Label_4                        matlab.ui.control.Label
        axis                           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        uwbRXFlag % Description
        uwbRXBuffer=zeros(100);%数据缓存
        uwbRXCnt % Description
        SerialObject % Description
        straxis=char(zeros(2,10)) % 存放x、y、z坐标的字符串数组
        virtual_time=1:10000 % Description
        uwb_axis=zeros(2,10000) % Description
        vtime_index=1 % Description
        while_time=1;
        err_time=30
        t_now; % Description
        t_last; % Description
        time_gap;
        freq;
        stranchor=char(zeros(1,10)) % Description
        strfusion_state=char(zeros(1,5));
    end
    
    methods (Access = private)
        
        function Deal_serial_data(app) %处理串口数据，逐帧分析
            if(app.uwbRXFlag==1)
                return;
            end
            app.uwbRXBuffer(app.uwbRXCnt)=app.SerialObject.UserData;
            app.uwbRXCnt=app.uwbRXCnt+1;
            if(app.uwbRXBuffer(1)~='[')
                app.uwbRXCnt=1;
                app.uwbRXFlag=0;
                return;
            end
            if(app.uwbRXBuffer(app.uwbRXCnt-1)~=']')
                return;
            else
                    app.uwbRXFlag=1;
            end
            Uwb_data_analysis(app);
        end
        
        function Uwb_data_analysis(app)
            if(app.uwbRXFlag==1)
                app.t_last=app.t_now;
                app.t_now=clock;
                app.time_gap=app.t_now(6)-app.t_last(6);
                app.freq=1/app.time_gap;
                if(app.freq>0)
                app.Hz.Value=app.freq;
                end
                read_index=1; %数据索引
                str_index=1;
                straxis_type_index=1;
                
                while(app.uwbRXBuffer(read_index)~='[')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    read_index=read_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                read_index=read_index+1;
                while(app.uwbRXBuffer(read_index)~=',')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.straxis(straxis_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                straxis_type_index=straxis_type_index+1;
                read_index=read_index+1;

                while(app.uwbRXBuffer(read_index)~=',')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.straxis(straxis_type_index,str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                str_index=1;
                read_index=read_index+1;
                while(app.uwbRXBuffer(read_index)~=',')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.stranchor(str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                
                while(app.uwbRXBuffer(read_index)~=']')
                    if(app.while_time>app.err_time)
                        break;
                    end
                    app.strfusion_state(str_index)=app.uwbRXBuffer(read_index);
                    read_index=read_index+1;
                    str_index=str_index+1;
                    app.while_time=app.while_time+1;
                end
                app.while_time=0;
                
                app.uwbRXFlag=0;
                app.uwbRXCnt=1;
                app.uwb_axis(1,app.vtime_index)=str2double(app.straxis(1,:));
                app.uwb_axis(2,app.vtime_index)=str2double(app.straxis(2,:));
                
                
                plot(app.axis,app.uwb_axis(1,app.vtime_index),app.uwb_axis(2,app.vtime_index),'.','Color','b','MarkerSize',8);
                hold (app.axis,'on');
                plot(app.axis,app.uwb_axis(1,1:app.vtime_index),app.uwb_axis(2,1:app.vtime_index),'-','Color','r','LineWidth',0.5);
                hold (app.axis,'on');
                
                if(app.stranchor(2)=='0')
                    app.anchor1.Color=[1,0,0];
                else
                    app.anchor1.Color=[0,1,0];
                end
                
                if(app.stranchor(3)=='0')
                    app.anchor2.Color=[1,0,0];
                else
                    app.anchor2.Color=[0,1,0];
                end
                
                if(app.stranchor(4)=='0')
                    app.anchor3.Color=[1,0,0];
                else
                    app.anchor3.Color=[0,1,0];
                end
                
                if(app.stranchor(5)=='0')
                    app.anchor4.Color=[1,0,0];
                else
                    app.anchor4.Color=[0,1,0];
                end
                
                if(app.strfusion_state(1)=='0')
                    app.test_txt.Value="uwb";
                else
                    app.test_txt.Value="odom";
                end
                
                if(app.vtime_index<10000)
                    app.vtime_index=app.vtime_index+1;
                else
                    app.vtime_index=1;
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        app.virtual_time=1:10000;
        app.uwbRXCnt=1;
        app.uwbRXFlag=0;
        app.vtime_index=1;
        app.t_now=clock;
        com_list=serialportlist("all");%找到可以用的串口号 
        hold(app.axis,"on");
        plot(app.axis,app.BS1_X.Value,app.BS1_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS2_X.Value,app.BS2_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS3_X.Value,app.BS3_Y.Value,'.','MarkerSize',20,"Color",'r');
        plot(app.axis,app.BS4_X.Value,app.BS4_Y.Value,'.','MarkerSize',20,"Color",'r');
        text(app.axis,app.BS1_X.Value,app.BS1_Y.Value,"BS1",'FontSize',14,"FontWeight","bold");
        text(app.axis,app.BS2_X.Value,app.BS2_Y.Value,"BS2","FontSize",14,"FontWeight","bold");
        text(app.axis,app.BS3_X.Value,app.BS3_Y.Value,"BS3","FontSize",14,"FontWeight","bold");
        text(app.axis,app.BS4_X.Value,app.BS4_Y.Value,"BS4","FontSize",14,"FontWeight","bold");
        axis(app.axis,[(app.BS1_X.Value-(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS2_X.Value+(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS1_Y.Value-(app.BS4_Y.Value-app.BS1_Y.Value)*0.1) (app.BS4_Y.Value+(app.BS4_Y.Value-app.BS1_Y.Value)*0.1)]);

        try
            app.SerialPortNums.Items=com_list;
            app.SerialBaud.Value="115200";  
        catch
            app.portData.PortName='null';
            errordlg('没有搜索到任何可用端口');
        end  
        end

        % Drop down opening function: SerialPortNums
        function SerialPortNumsDropDownOpening(app, event)
           com_list=serialportlist("all");%找到可以用的串口号 
           try
                app.SerialPortNums.Items=com_list;
                app.SerialBaud.Value="115200";  
           catch
                app.portData.PortName='null';
                errordlg('没有搜索到任何可用端口');
           end
        end

        % Value changed function: SerialSwitch
        function SerialSwitchValueChanged(app, event)
            value = app.SerialSwitch.Value;
            app.test_txt.Value='start';
            if(value=="Open")
                try
%                      delete(serialport)%注销系统之前已经打开的串口资源
                     app.SerialObject=serialport( ...
                     app.SerialPortNums.Value, ...  %设置端口号
                     str2double(app.SerialBaud.Value),...%设置波特率
                    'StopBits',str2double(app.SerialStopBit.Value),...%设置停止位
                    'Parity',app.SerialParity.Value);%设置校验位
                    configureCallback(app.SerialObject,"byte",1,@readSerialData)%设置回调函数readSerialData 接受串口返回的数据 
                    app.serial_state.Color=[0,1,0];%绿色  将指示灯打开
                catch
                     errordlg('error:该串口可能被别的应用占用或串口命名错误！');
                     app.SerialSwitch.Value='Close';
                     return
                end
            elseif (value=="Close")
                 delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                 app.serial_state.Color=[1,1,1];%白色  将指示灯关闭
            end
            %串口接受数据的回调函数
            function readSerialData(src,event)
              app.SerialObject.UserData=read(app.SerialObject,1,"uint8");

              Deal_serial_data(app);%处理接收到的串口数据
           end
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            app.uwbRXCnt=1;
            app.uwbRXFlag=0;
            app.vtime_index=1;
            hold (app.axis,'off');
            axis(app.axis,[(app.BS1_X.Value-(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS2_X.Value+(app.BS2_X.Value-app.BS1_X.Value)*0.1) (app.BS1_Y.Value-(app.BS4_Y.Value-app.BS1_Y.Value)*0.1) (app.BS4_Y.Value+(app.BS4_Y.Value-app.BS1_Y.Value)*0.1)]);
            plot(app.axis,app.BS1_X.Value,app.BS1_Y.Value,'.','MarkerSize',20,"Color",'r');
            hold(app.axis,'on');
            plot(app.axis,app.BS2_X.Value,app.BS2_Y.Value,'.','MarkerSize',20,"Color",'r');
            plot(app.axis,app.BS3_X.Value,app.BS3_Y.Value,'.','MarkerSize',20,"Color",'r');
            plot(app.axis,app.BS4_X.Value,app.BS4_Y.Value,'.','MarkerSize',20,"Color",'r');
            text(app.axis,app.BS1_X.Value,app.BS1_Y.Value,"BS1",'FontSize',14,"FontWeight","bold");
            text(app.axis,app.BS2_X.Value,app.BS2_Y.Value,"BS2","FontSize",14,"FontWeight","bold");
            text(app.axis,app.BS3_X.Value,app.BS3_Y.Value,"BS3","FontSize",14,"FontWeight","bold");
            text(app.axis,app.BS4_X.Value,app.BS4_Y.Value,"BS4","FontSize",14,"FontWeight","bold");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.axis = uiaxes(app.UIFigure);
            title(app.axis, 'Axis')
            xlabel(app.axis, 'X')
            ylabel(app.axis, 'Y')
            zlabel(app.axis, 'Z')
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '曹宇辉';
            app.UWBFusionDataDisplayToolLabel = uilabel(app.UIFigure);
            app.UWBFusionDataDisplayToolLabel.Text = 'UWB Fusion Data Display Tool';
            app.EquipmentParametersPanel = uipanel(app.UIFigure);
            app.EquipmentParametersPanel.Title = 'Equipment Parameters';
            app.COMDropDownLabel = uilabel(app.EquipmentParametersPanel);
            app.COMDropDownLabel.Text = 'COM';
            app.SerialPortNums = uidropdown(app.EquipmentParametersPanel);
            app.SerialPortNums.Items = {'', ''};
            app.SerialPortNums.DropDownOpeningFcn = createCallbackFcn(app, @SerialPortNumsDropDownOpening, true);
            app.SerialPortNums.Value = '';
            app.StopLabel = uilabel(app.EquipmentParametersPanel);
            app.StopLabel.Text = 'Stop';
            app.SerialStopBit = uidropdown(app.EquipmentParametersPanel);
            app.SerialStopBit.Items = {'1', '1.5', '2'};
            app.SerialStopBit.Value = '1';
            app.BaudDropDownLabel = uilabel(app.EquipmentParametersPanel);
            app.BaudDropDownLabel.Text = 'Baud';
            app.SerialBaud = uidropdown(app.EquipmentParametersPanel);
            app.SerialBaud.Items = {'2400', '4800', '9600', '19200', '38400', '57600', '115200', '128000'};
            app.SerialBaud.Value = '2400';
            app.ParityLabel = uilabel(app.EquipmentParametersPanel);
            app.ParityLabel.Text = 'Parity';
            app.SerialParity = uidropdown(app.EquipmentParametersPanel);
            app.SerialParity.Items = {'None', 'odd', 'Even'};
            app.SerialParity.Value = 'None';
            app.SerialSwitch = uiswitch(app.EquipmentParametersPanel, 'slider');
            app.SerialSwitch.Items = {'Open', 'Close'};
            app.SerialSwitch.ValueChangedFcn = createCallbackFcn(app, @SerialSwitchValueChanged, true);
            app.SerialSwitch.Value = 'Close';
            app.StatusesLabel = uilabel(app.EquipmentParametersPanel);
            app.StatusesLabel.Text = 'Statuses';
            app.serial_state = uilamp(app.EquipmentParametersPanel);
            app.test_txt = uieditfield(app.EquipmentParametersPanel, 'text');
            app.BS_AxismPanel = uipanel(app.UIFigure);
            app.BS_AxismPanel.Title = 'BS_Axis(m)';
            app.BS2_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2_X.Value = 5;
            app.BS3_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS3_Y.Value = 5;
            app.BS3_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS3_X.Value = 5;
            app.BS4_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS4_Y.Value = 5;
            app.BS4_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS2Label = uilabel(app.BS_AxismPanel);
            app.BS2Label.Text = 'BS2';
            app.BS3Label = uilabel(app.BS_AxismPanel);
            app.BS3Label.Text = 'BS3';
            app.BS4Label = uilabel(app.BS_AxismPanel);
            app.BS4Label.Text = 'BS4';
            app.BS1_X = uieditfield(app.BS_AxismPanel, 'numeric');
            app.BS1_Y = uieditfield(app.BS_AxismPanel, 'numeric');
            app.XLabel = uilabel(app.BS_AxismPanel);
            app.XLabel.Text = 'X';
            app.YLabel = uilabel(app.BS_AxismPanel);
            app.YLabel.Text = 'Y';
            app.BS1Label = uilabel(app.BS_AxismPanel);
            app.BS1Label.Text = 'BS1';
            app.SetButton = uibutton(app.BS_AxismPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '南京航空航天大学自动化学院';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '测控技术与仪器专业';
            app.AuthorLabel = uilabel(app.UIFigure);
            app.AuthorLabel.Text = 'Author：';
            app.Hz = uieditfield(app.UIFigure, 'numeric');
            app.fHzLabel = uilabel(app.UIFigure);
            app.fHzLabel.Text = 'f(Hz)';
            app.anchor4 = uilamp(app.UIFigure);
            app.anchor3 = uilamp(app.UIFigure);
            app.anchor1 = uilamp(app.UIFigure);
            app.anchor2 = uilamp(app.UIFigure);
            app.BS4Label_2 = uilabel(app.UIFigure);
            app.BS4Label_2.Text = 'BS4';
            app.BS1Label_2 = uilabel(app.UIFigure);
            app.BS1Label_2.Text = 'BS1';
            app.BS2Label_2 = uilabel(app.UIFigure);
            app.BS2Label_2.Text = 'BS2';
            app.BS4Label_5 = uilabel(app.UIFigure);
            app.BS4Label_5.Text = 'BS4';
            app.BS3Label_2 = uilabel(app.UIFigure);
            app.BS3Label_2.Text = 'BS3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UWB_ODOM_Fusion

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end